classdef CISIAproDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CISIAproDesignUIFigure          matlab.ui.Figure
        Label                           matlab.ui.control.Label
        TopImage                        matlab.ui.control.Image
        Image3_3                        matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        DatabaseTab                     matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        ProjectPanel                    matlab.ui.container.Panel
        ImportProjectButton             matlab.ui.control.Button
        OnlyActiveEntitiesCheckBox      matlab.ui.control.CheckBox
        ExportProjectButton             matlab.ui.control.Button
        id_projectEditField             matlab.ui.control.NumericEditField
        id_projectEditFieldLabel        matlab.ui.control.Label
        RenameProjectButton             matlab.ui.control.Button
        nameEditField_3                 matlab.ui.control.EditField
        nameEditField_3Label            matlab.ui.control.Label
        NewEmptyProjectButton           matlab.ui.control.Button
        DuplicateProjectButton          matlab.ui.control.Button
        ChooseProjectDropDown_2         matlab.ui.control.DropDown
        ChooseProjectDropDown_2Label    matlab.ui.control.Label
        ReadProjectsButton_2            matlab.ui.control.Button
        DatabasePanel                   matlab.ui.container.Panel
        JavaConnectorEditField          matlab.ui.control.EditField
        JavaConnectorEditFieldLabel     matlab.ui.control.Label
        UserNameDropDown                matlab.ui.control.DropDown
        UserNameDropDownLabel           matlab.ui.control.Label
        urlDropDown                     matlab.ui.control.DropDown
        urlDropDownLabel                matlab.ui.control.Label
        choosecatalogDropDown           matlab.ui.control.DropDown
        choosecatalogDropDownLabel      matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditField_2Label        matlab.ui.control.Label
        MessageDBLabel                  matlab.ui.control.Label
        ConnectiondataTextArea          matlab.ui.control.TextArea
        ConnectButton                   matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        EntityMakerTab                  matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        CreateDeleteTab                 matlab.ui.container.Tab
        RenameEntityTypeButton          matlab.ui.control.Button
        DeleteEntityButton              matlab.ui.control.Button
        DeleteEntityTypeButton          matlab.ui.control.Button
        RenameEntityButton_2            matlab.ui.control.Button
        newEntityofEntityTypeButton     matlab.ui.control.Button
        newemptyEntityTypeButton        matlab.ui.control.Button
        typeDropDown                    matlab.ui.control.DropDown
        typeDropDownLabel               matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        ExportInportTab                 matlab.ui.container.Tab
        InportEntityButton              matlab.ui.control.Button
        duplicateEntityTypeButton       matlab.ui.control.Button
        ExportEntityButton              matlab.ui.control.Button
        insidetheprojectDropDown        matlab.ui.control.DropDown
        insidetheprojectDropDownLabel   matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        StatevariablesTab               matlab.ui.container.Tab
        UITable10                       matlab.ui.control.Table
        MultipleAddTab                  matlab.ui.container.Tab
        AutoDiscoverButton              matlab.ui.control.Button
        ShowVariablesButton             matlab.ui.control.Button
        AddSelectedButton               matlab.ui.control.Button
        UITable20                       matlab.ui.control.Table
        Panel2                          matlab.ui.container.Panel
        ActualProjectEditField          matlab.ui.control.EditField
        ActualProjectEditFieldLabel     matlab.ui.control.Label
        EntityTypeDropDown              matlab.ui.control.DropDown
        EntityTypeDropDownLabel         matlab.ui.control.Label
        id_typeEditField                matlab.ui.control.EditField
        id_typeEditFieldLabel           matlab.ui.control.Label
        ColorpickerButton               matlab.ui.control.Button
        colorEditField                  matlab.ui.control.EditField
        colorEditFieldLabel             matlab.ui.control.Label
        entitytypeTextArea              matlab.ui.control.TextArea
        entitytypeTextAreaLabel         matlab.ui.control.Label
        descriptionTextArea             matlab.ui.control.TextArea
        descriptionTextAreaLabel        matlab.ui.control.Label
        fourstepsdescriptionentitytypenamecolorLabel  matlab.ui.control.Label
        updateEntityTypebehaviorButton  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        step_receivedTab                matlab.ui.container.Tab
        step_receivedTextArea           matlab.ui.control.TextArea
        step_computedTab                matlab.ui.container.Tab
        step_computedTextArea           matlab.ui.control.TextArea
        dynamic_computedTab             matlab.ui.container.Tab
        dynamic_computedTextArea        matlab.ui.control.TextArea
        step_sendedTab                  matlab.ui.container.Tab
        step_sendedTextArea             matlab.ui.control.TextArea
        Panel                           matlab.ui.container.Panel
        filterentitynameEditField       matlab.ui.control.EditField
        filterentitynameEditFieldLabel  matlab.ui.control.Label
        UpdatestatusButton              matlab.ui.control.Button
        UITable9                        matlab.ui.control.Table
        UITable8                        matlab.ui.control.Table
        choosevariableDropDown          matlab.ui.control.DropDown
        choosevariableDropDownLabel     matlab.ui.control.Label
        AddVariableButton               matlab.ui.control.Button
        RemoveVarButton                 matlab.ui.control.Button
        chooseoutputDropDown            matlab.ui.control.DropDown
        chooseoutputDropDownLabel       matlab.ui.control.Label
        AddOutputButton                 matlab.ui.control.Button
        RemoveOutputButton              matlab.ui.control.Button
        chooseinputDropDown             matlab.ui.control.DropDown
        chooseinputDropDownLabel        matlab.ui.control.Label
        AddInputButton                  matlab.ui.control.Button
        RemoveInputButton               matlab.ui.control.Button
        id_entityEditField              matlab.ui.control.NumericEditField
        id_entityEditFieldLabel         matlab.ui.control.Label
        chooseDropDown                  matlab.ui.control.DropDown
        chooseDropDownLabel             matlab.ui.control.Label
        readallentitiesButton           matlab.ui.control.Button
        StateVariablesTab               matlab.ui.container.Tab
        entitieswithselectedvariableLabel  matlab.ui.control.Label
        UITable3_2                      matlab.ui.control.Table
        variablestobedeletedLabel       matlab.ui.control.Label
        UITable5                        matlab.ui.control.Table
        ProjectEditField_2              matlab.ui.control.EditField
        ProjectEditField_2Label         matlab.ui.control.Label
        UpdateTableButton               matlab.ui.control.Button
        DeleteSelectedButton_2          matlab.ui.control.Button
        AddarowButton_2                 matlab.ui.control.Button
        LoadVariablesButton             matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        LayersResourcesTab              matlab.ui.container.Tab
        entitieswithselectedresourceLabel  matlab.ui.control.Label
        UITable2_2                      matlab.ui.control.Table
        CleanPortsButton                matlab.ui.control.Button
        colorEditField_2                matlab.ui.control.EditField
        colorEditField_2Label           matlab.ui.control.Label
        ColorpickerButton_2             matlab.ui.control.Button
        resourcestobedeletedLabel       matlab.ui.control.Label
        UITable6                        matlab.ui.control.Table
        UpdatetableButton               matlab.ui.control.Button
        DeleteSelectedButton            matlab.ui.control.Button
        AddarowButton                   matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        LoadResourcesButton             matlab.ui.control.Button
        ProjectEditField                matlab.ui.control.EditField
        ProjectEditFieldLabel           matlab.ui.control.Label
        RoutingTab                      matlab.ui.container.Tab
        RoutersPanel                    matlab.ui.container.Panel
        RouterstobedeletedLabel         matlab.ui.control.Label
        UITable18                       matlab.ui.control.Table
        UpdateTableButton_7             matlab.ui.control.Button
        DeleteSelectedButton_7          matlab.ui.control.Button
        AddarowButton_7                 matlab.ui.control.Button
        LoadRoutersButton               matlab.ui.control.Button
        UITable17                       matlab.ui.control.Table
        RoutesPanel                     matlab.ui.container.Panel
        RoutestobedeletedLabel          matlab.ui.control.Label
        UITable16                       matlab.ui.control.Table
        UpdateTableButton_6             matlab.ui.control.Button
        DeleteSelectedButton_6          matlab.ui.control.Button
        AddarowButton_6                 matlab.ui.control.Button
        LoadRoutesButton                matlab.ui.control.Button
        UITable15                       matlab.ui.control.Table
        ProjectEditField_6              matlab.ui.control.EditField
        ProjectEditField_6Label         matlab.ui.control.Label
        LinkStateTab                    matlab.ui.container.Tab
        LinkstobedeletedLabel           matlab.ui.control.Label
        UITable7                        matlab.ui.control.Table
        ProjectEditField_3              matlab.ui.control.EditField
        ProjectEditField_3Label         matlab.ui.control.Label
        UpdateTableButton_2             matlab.ui.control.Button
        DeleteSelectedButton_3          matlab.ui.control.Button
        AddarowButton_3                 matlab.ui.control.Button
        LoadLinksButton                 matlab.ui.control.Button
        UITable4                        matlab.ui.control.Table
        RadialDiffusionTab              matlab.ui.container.Tab
        ProjectEditField_4              matlab.ui.control.EditField
        ProjectEditField_4Label         matlab.ui.control.Label
        RadialtobedeletedLabel          matlab.ui.control.Label
        UITable12                       matlab.ui.control.Table
        UpdateTableButton_3             matlab.ui.control.Button
        DeleteSelectedButton_4          matlab.ui.control.Button
        AddarowButton_4                 matlab.ui.control.Button
        LoadRadialButton                matlab.ui.control.Button
        UITable11                       matlab.ui.control.Table
        Save2DBTab                      matlab.ui.container.Tab
        ProjectEditField_5              matlab.ui.control.EditField
        ProjectEditField_5Label         matlab.ui.control.Label
        Save2DBtobedeletedLabel         matlab.ui.control.Label
        UITable14                       matlab.ui.control.Table
        UpdateTableButton_5             matlab.ui.control.Button
        DeleteSelectedButton_5          matlab.ui.control.Button
        AddarowButton_5                 matlab.ui.control.Button
        LoadSave2DBButton               matlab.ui.control.Button
        UITable13                       matlab.ui.control.Table
        RunManager                      matlab.ui.container.Tab
        ModelpicturePanel               matlab.ui.container.Panel
        Image6                          matlab.ui.control.Image
        RunningpanelPanel               matlab.ui.container.Panel
        modelresetLamp                  matlab.ui.control.Lamp
        modelresetLampLabel             matlab.ui.control.Label
        MessagesEditField               matlab.ui.control.EditField
        MessagesEditFieldLabel          matlab.ui.control.Label
        EnableModelChangeSwitch         matlab.ui.control.Switch
        EnableModelChangeSwitchLabel    matlab.ui.control.Label
        modelchangingLamp               matlab.ui.control.Lamp
        modelchangingLampLabel          matlab.ui.control.Label
        EnableFastRestartSwitch         matlab.ui.control.Switch
        EnableFastRestartSwitchLabel    matlab.ui.control.Label
        runningtimeEditField            matlab.ui.control.NumericEditField
        runningtimeEditFieldLabel       matlab.ui.control.Label
        StoprunningButton               matlab.ui.control.Button
        checkingLamp                    matlab.ui.control.Lamp
        checkingLampLabel               matlab.ui.control.Label
        runningLamp                     matlab.ui.control.Lamp
        runningLampLabel                matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        singlerunSwitch                 matlab.ui.control.Switch
        singlerunSwitchLabel            matlab.ui.control.Label
        ModelselectionPanel             matlab.ui.container.Panel
        pathEditField                   matlab.ui.control.EditField
        pathEditFieldLabel              matlab.ui.control.Label
        modelnameEditField              matlab.ui.control.EditField
        modelnameEditFieldLabel         matlab.ui.control.Label
        SelectmodelButton               matlab.ui.control.Button
        OutputRun                       matlab.ui.container.Tab
        DeleteallrunButton              matlab.ui.control.Button
        TotalRecordsEditField           matlab.ui.control.NumericEditField
        TotalRecordsEditFieldLabel      matlab.ui.control.Label
        runDropDown                     matlab.ui.control.DropDown
        runDropDownLabel                matlab.ui.control.Label
        chooseDropDown_2                matlab.ui.control.DropDown
        readallentitiesButton_2         matlab.ui.control.Button
        LoadLastRunButton               matlab.ui.control.Button
        UITable19                       matlab.ui.control.Table
        SavedStateTab                   matlab.ui.container.Tab
        modelsDropDown                  matlab.ui.control.DropDown
        ReadAllModels                   matlab.ui.control.Button
        ReadModelInRun                  matlab.ui.control.Button
        Gauge                           matlab.ui.control.LinearGauge
        Image7_2                        matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        SetOL                           matlab.ui.control.Button
        ShowModelStatus                 matlab.ui.control.Button
        UITable3_4                      matlab.ui.control.Table
        LoadStatus                      matlab.ui.control.Button
        SaveStatus                      matlab.ui.control.Button
        CleanStatus                     matlab.ui.control.Button
        modelnameEditField_2            matlab.ui.control.EditField
        modelnameEditField_2Label       matlab.ui.control.Label
        UITable3_3                      matlab.ui.control.Table
        LoadInitialStatus               matlab.ui.control.Button
        UtilitiesTab                    matlab.ui.container.Tab
        Panel3                          matlab.ui.container.Panel
        SaveGraphButton                 matlab.ui.control.Button
        RepaintblocksaccordingtoaselectedstatevariablePanel  matlab.ui.container.Panel
        ReverseCheckBox                 matlab.ui.control.CheckBox
        RepaintEntitiesButton           matlab.ui.control.Button
        StateVariablesDropDown          matlab.ui.control.DropDown
        StateVariablesDropDownLabel     matlab.ui.control.Label
        SelectonestatevariableLabel     matlab.ui.control.Label
        ReadEntitiesandStateVariablesButton  matlab.ui.control.Button
        Gauge_2                         matlab.ui.control.LinearGauge
        EntityprocessedEditField        matlab.ui.control.EditField
        EntityprocessedEditFieldLabel   matlab.ui.control.Label
        WriteEntitiestoFIleButton       matlab.ui.control.Button
        WriteentitiestofileExcellPanel  matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
    end

    
    properties (Access = private)
       connection=0;
       connected=false;
       catalog=false;
       id_project=0;
       id_entity=0;
       name_entity;
       id_type;
       name_project;
       num_resources;
       indices;
       num_variables;
       num_entity_variables;
       num_links;
       var_deleted;
       res_deleted;
       link_deleted;
       num_routes;
       %routes_deleted;
       id_res;
       id_res_in;
       id_res_out;
       name_res;
       id_port;
       num_radial;
       radial_deleted;
       num_save2db;
       save2db_deleted;
       last_save2db;
       cell_array;
       value;
       last_variable;
       num_route_link;
       last_routing;
       last_route_link;
       route_deleted;
       route_link_deleted;
       file;
       path;
       cycle_run;
       id_run;
       model;
       var_rand;
       res_rand;
       last_url;
       last_user;
       last_catalog;
       last_password;
       last_project;
       last_connector;
       selected_variables;
       Links;
    end
    
    methods (Access = private)
        
        function results = new_port(app)
            query='SELECT id_port from entity_port order by id_port';
            data=fetch(app.connection,query);
            if height(data)==0
                results=1;
            else
                results=max(data{:,1})+1;
            end
            
        end

        
        function results = new_variable(app)
            query='SELECT id_var from variables order by id_var';
            data=fetch(app.connection,query);
            if height(data)==0
                results=1;
            else
                results=max(data{:,1})+1;
            end
            
        end
        
        function results = read_state_variables(app)
         
            %query=strcat('SELECT * FROM status AS a JOIN variables AS b ON a.id_var=b.id_var where a.id_entity=''',string(app.id_entity),''' ORDER BY a.id_var');
            query=strcat('SELECT b.name_var, b.val_type, b.var_dim, a.val_status FROM status AS a JOIN variables AS b ON a.id_var=b.id_var where a.id_entity=''',string(app.id_entity),''' ORDER BY b.name_var');
            data5=fetch(app.connection, query);
            num_variables=height(data5);
            app.UITable10.Data=[];
            if num_variables>0
                app.UITable10.Data=data5;
            end
            
            results=0;
            
        end
        
        function results = convert_to_string(app)
            n=size(app.cell_array,1);
            b=app.cell_array{1,1};
            if n>1
                for i=2:n
                    b=[b char(10) app.cell_array{i,1}];
                end
            end
            results=b;
            
        end
        
        function results = read_inputs(app)
            query=strcat('SELECT name_res, dim_res FROM resources AS a JOIN  entity_port AS b  ON a.id_res=b.id_res  WHERE id_entity=''',string(app.id_entity),''' AND type_port=''INPUT'' ORDER BY a.id_res');
            data3=fetch(app.connection, query);
            app.UITable8.Data=[];
            if height(data3)>0
                app.UITable8.Data=data3;
            end
            results=1;
            
        end
        
        function results = read_outputs(app)
            query=strcat('SELECT name_res, dim_res FROM resources AS a JOIN  entity_port AS b  ON a.id_res=b.id_res  WHERE id_entity=''',string(app.id_entity),''' AND type_port=''OUTPUT'' ORDER BY a.id_res');
            data4=fetch(app.connection, query);
            app.UITable9.Data=[];
            if height(data4)>0
                app.UITable9.Data=data4;
            end
            results=1;
        end
        
        function results = new_entity(app)
            query='SELECT id_entity from entity order by id_entity';
            data=fetch(app.connection,query);
            if height(data)==0
                results=1;
            else
                results=max(data{:,1})+1;
            end
            
        end
        
        function results = new_id_type(app)
            query='SELECT id_type from entity_type order by id_type';
            data=fetch(app.connection,query);
            if height(data)==0
                results=1;
            else
                results=max(data{:,1})+1;
            end
            
        end
        
        function results = read_entity_type(app)
            query2=strcat('SELECT * FROM entity_type WHERE name_type=''',app.value,'''', ' AND id_project=',string(app.id_project));
            data2=fetch(app.connection, query2);
            app.step_receivedTextArea.Value=data2.step_received(1);
            app.step_computedTextArea.Value=data2.step_computed(1);
            app.dynamic_computedTextArea.Value=data2.dynamic_computed(1);
            app.step_sendedTextArea.Value=data2.step_sended(1);
            app.entitytypeTextArea.Value=data2.name_type(1);
            app.descriptionTextArea.Value=data2.description(1);
            app.id_typeEditField.Value=num2str(data2.id_type(1));
            app.colorEditField.Value=data2.color{1};
            app.id_type=data2.id_type(1);
            results=data2.id_type(1);
            
        end
        
        function results = new_route_link(app)
            query='SELECT id_routing from route_link order by id_routing';
            data=fetch(app.connection,query);
            if height(data)==0
                results=1;
            else
                results=max(data{:,1})+1;
            end
        end
        
        function results = new_routing(app)
            query='SELECT id_route from routing_table order by id_route';
            data=fetch(app.connection,query);
            if height(data)==0
                results=1;
            else
                results=max(data{:,1})+1;
            end
        end
        
        function results = new_save2db(app)
            query='SELECT id_save2db from save2db order by id_save2db';
            data=fetch(app.connection,query);
            if height(data)==0
                results=1;
            else
                results=max(data{:,1})+1;
            end
        end


        function results=read_all_entity_type(app)
            
            query2=strcat('SELECT * FROM entity_type WHERE id_project=',num2str(app.id_project),' ORDER by name_type ASC ');
            data2=fetch(app.connection, query2);
            if height(data2)>0
                app.EntityTypeDropDown.Items=data2.name_type;
                app.typeDropDown.Items=data2.name_type;
            else
                entities=cell(1,1);
                entities{1,1}='<empty>';
                app.EntityTypeDropDown.Items=entities;
                app.typeDropDown.Items=entities;
            end
            results=1;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
                         
            if exist('sql_ini.m','file')
                sql_ini
                app.urlDropDown.Items=HOST;
                app.UserNameDropDown.Items=USER;
                app.PasswordEditField.Value=PSW;
                
            end
            
            if exist('schema.mat','file')
                load('schema', '-mat','last_url','last_user','last_password','last_catalog','last_project','last_connector')
                app.last_url=last_url;
                app.last_user=last_user;
                app.last_password=last_password;
                app.last_catalog=last_catalog;
                app.last_project=last_project;
                app.JavaConnectorEditField.Value=last_connector;
                
                app.urlDropDown.Value=app.last_url;
                app.UserNameDropDown.Value=app.last_user;
                app.PasswordEditField.Value=app.last_password;
            else
                app.last_url=app.urlDropDown.Value;
                app.last_user=app.UserNameDropDown.Value;
                app.last_password=app.PasswordEditField.Value;
                app.last_catalog=app.choosecatalogDropDown.Value;
                app.last_project=app.ChooseProjectDropDown_2.Value;
                app.last_connector=app.JavaConnectorEditField.Value;
            end


        end

        % Callback function
        function Button_5Pushed(app, event)
            
            app.TabGroup.SelectedTab = app.DatabaseTab;
            
            
        end

        % Callback function
        function ButtonPushed(app, event)
        
            if app.id_project>0
                app.TabGroup.SelectedTab = app.EntityMakerTab;
            else
                if app.connected
                    f = errordlg('Please select a project');
                    
                else
                    f = errordlg('Please open a conenction');
                    
                end
            end


        end

        % Callback function
        function Button_2Pushed(app, event)
            
            
             if app.id_project>0
                app.TabGroup.SelectedTab = app.LayersResourcesTab;
                app.ProjectEditField.Value=app.name_project;
            else
                if app.connected
                    f = errordlg('Please select a project');
                    
                else
                    f = errordlg('Please open a conenction');
                    
                end
            end


        end

        % Callback function
        function Button_3Pushed(app, event)
            
            
            if app.id_project>0
                app.TabGroup.SelectedTab = app.StateVariablesTab;
            else
                if app.connected
                    f = errordlg('Please select a project');
                else
                    f = errordlg('Please open a conenction');
                end
            end
            
        end

        % Callback function
        function Button_4Pushed(app, event)
            
            
            if app.id_project>0
                app.TabGroup.SelectedTab = app.LinkStateTab;
            else
                if app.connected
                    f = errordlg('Please select a project');
                    
                else
                    f = errordlg('Please open a conenction');
                    
                end
            end
            
            
        end

        % Callback function
        function ConnectButtonPushed(app, event)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed2(app, event)

        app.connected=false;

        try
            %disp(app.JavaConnectorEditField.Value)
            javaaddpath app.JavaConnectorEditField.Value
        catch
            %f = errordlg('Connection not opened!');
        end
        
        try
            databasename = '';
            driver = 'com.mysql.cj.jdbc.Driver';
            url = ['jdbc:mysql://' app.urlDropDown.Value '/?serverTimezone=UTC'];
            %conn = database(databasename,app.UserNameEditField.Value,app.PasswordEditField.Value,driver,url);
            conn = database(databasename,app.UserNameDropDown.Value,app.PasswordEditField.Value,driver,url);
            
            app.connection=conn;
            app.ConnectiondataTextArea.Value=['Message:' app.connection.Message ' Default catalog:' app.connection.DefaultCatalog];
            app.connected=true;
            app.choosecatalogDropDown.Items=conn.Catalogs;
            
                        
        catch 
            f = errordlg('Connection not opened!');
            
            app.ConnectiondataTextArea.Value='Unconnected';
        end
       
        if app.connected    
            if size(conn.Catalogs,2)>0
                app.choosecatalogDropDown.ValueChangedFcn(app,event);
            end
            app.last_url=app.urlDropDown.Value;
            app.last_user=app.UserNameDropDown.Value;
            app.last_password=app.PasswordEditField.Value;
            app.last_connector=app.JavaConnectorEditField.Value;
            
            last_url=app.last_url;
            last_user=app.last_user;
            last_password=app.last_password;
            last_catalog=app.last_catalog;
            last_project=app.last_project;
            last_connector=app.last_connector;
            
            save('schema.mat','last_url','last_user','last_password','last_catalog','last_project','last_connector')
            
        end


        end

        % Callback function
        function ReadProjectsButtonPushed(app, event)
            query=strcat('SELECT * FROM projects');
            data=fetch(app.connection, query);
            app.ChooseProjectDropDown.Items=data.name_project(1);
            app.id_project = data.id_project(1);
        end

        % Button pushed function: readallentitiesButton
        function readallentitiesButtonPushed(app, event)
            query=strcat('SELECT * FROM entity WHERE id_project=''',num2str(app.id_project),''' ORDER BY name_entity');
            data=fetch(app.connection, query);
            
            items=cell(height(data),1);
            for i=1:height(data)
                items{i}=data.name_entity{i};
            end
            
            app.chooseDropDown.Items=items;
            
%             if height(data)>0
%                 a=app.read_entity_type();
%             end


        end

        % Callback function
        function ChooseProjectDropDownValueChanged(app, event)
            app.name_project = app.ChooseProjectDropDown.Value;
            
            % bisogna cercare l'id_project
            f = errordlg('Not yet implemented');
            
            disp('to be completed choose project')
            
        end

        % Value changed function: chooseDropDown
        function chooseDropDownValueChanged(app, event)
        
            
            app.name_entity = app.chooseDropDown.Value;
            % bisogna cercare l'id_entity
            query=strcat('SELECT * FROM entity WHERE name_entity=''',app.name_entity,''' AND id_project=''',num2str(app.id_project),'''');
            data=fetch(app.connection, query);
            app.id_type=data.id_type(1);
            app.id_entity=data.id_entity(1);
            app.id_entityEditField.Value=app.id_entity;
            
            
            % Tabella entity_type
            query2=strcat('SELECT * FROM entity_type WHERE id_type=',string(app.id_type),' AND id_project=''',num2str(app.id_project),'''');
            data2=fetch(app.connection, query2);
            app.step_receivedTextArea.Value=data2.step_received(1);
            app.step_computedTextArea.Value=data2.step_computed(1);
            app.dynamic_computedTextArea.Value=data2.dynamic_computed(1);
            app.step_sendedTextArea.Value=data2.step_sended(1);
            app.entitytypeTextArea.Value=data2.name_type(1);
            app.EntityTypeDropDown.Value=data2.name_type(1);
            app.descriptionTextArea.Value=data2.description(1);
            app.id_typeEditField.Value=num2str(data2.id_type(1));
            app.colorEditField.Value=data2.color{1};

            % tabella entity_port
                        
            % Inputs
            app.read_inputs();
            
            
            % Outputs
            app.read_outputs();
            
            % state variables
            
            app.read_state_variables();
            
%             query=strcat('SELECT * FROM status AS a JOIN variables AS b ON a.id_var=b.id_var where a.id_entity=''',string(app.id_entity),'''');
%             data5=fetch(app.connection, query);
%             num_variables=height(data5);
%             app.UITable10.Data=cell(0,3)
%             if num_variables>0
%                 for i=1:num_variables
%                     app.UITable10.Data{i,1}=data5.name_var{i};
%                     app.UITable10.Data{i,2}=data5.val_type{i};
%                     app.UITable10.Data{i,3}=data5.val_status{i};
%                     
%                 end
%             end


            query=strcat('SELECT * FROM resources WHERE id_project=',num2str(app.id_project),' ORDER BY name_res');
            data6=fetch(app.connection, query);
            if height(data6)>0
                app.chooseinputDropDown.Items=data6.name_res;
                app.chooseoutputDropDown.Items=data6.name_res;
                
                chooseinputDropDownValueChanged(app);
                chooseoutputDropDownValueChanged(app);
                
            end
            
            query=strcat('SELECT * FROM variables WHERE id_project=',num2str(app.id_project), ' ORDER BY name_var');
            data7=fetch(app.connection, query);
            if height(data7)>0
                app.choosevariableDropDown.Items=data7.name_var;
            end
            
            %app.OutputResourcesNode.reset


        end

        % Button pushed function: ReadProjectsButton_2
        function ReadProjectsButton_2Pushed(app, event)
        
        if app.connected
            query=strcat('SELECT * FROM projects');
            data=fetch(app.connection, query);
            if height(data)>0
                app.ChooseProjectDropDown_2.Items=data.name_project;
                app.insidetheprojectDropDown.Items=data.name_project;
                
                app.name_project=string(data.name_project(1));
                app.id_project = data.id_project(1);
                app.id_projectEditField.Value=app.id_project;
                app.ActualProjectEditField.Value=data.name_project{1};
                app.ProjectEditField.Value=data.name_project{1};
                app.ProjectEditField_2.Value=data.name_project{1};
                app.ProjectEditField_3.Value=data.name_project{1};
                app.ProjectEditField_4.Value=data.name_project{1};
                app.ProjectEditField_5.Value=data.name_project{1};
                app.ProjectEditField_6.Value=data.name_project{1};


                query2=strcat('SELECT * FROM entity_type WHERE id_project=',num2str(app.id_project),' ORDER BY name_type');
                data2=fetch(app.connection, query2);
                if height(data2)>0
                    app.EntityTypeDropDown.Items=data2.name_type;
                    app.typeDropDown.Items=data2.name_type;
                end
            end
            
        else
            f = errordlg('Database not connected!');
            
        
        end
        
            
        end

        % Value changed function: ChooseProjectDropDown_2
        function ChooseProjectDropDown_2ValueChanged(app, event)
            project = app.ChooseProjectDropDown_2.Value;
            
            
            app.last_project=project;
            
            app.ActualProjectEditField.Value=project;
            app.insidetheprojectDropDown.Value=project;
            
            app.name_project=project;
            app.ProjectEditField.Value=project;
            app.ProjectEditField_2.Value=project;
            app.ProjectEditField_3.Value=project;
            app.ProjectEditField_4.Value=project;
            app.ProjectEditField_5.Value=project;
            app.ProjectEditField_6.Value=project;
            
            
            data=fetch(app.connection, ['SELECT * FROM projects where name_project=''' app.name_project '''']);
            app.id_project=data.id_project(1);
            app.id_projectEditField.Value=app.id_project;


            read_all_entity_type(app);
            
        end

        % Value changed function: ActualProjectEditField
        function ActualProjectEditFieldValueChanged(app, event)
%             value = app.ActualProjectEditField.Value;
%             query=strcat('SELECT * FROM projects where id_project=''',value,'''');
%             data=fetch(app.connection, query);
%             app.id_project=data.id_project;
%             app.name_project=string(data.name_project);
%             
%             query2=strcat('SELECT * FROM entity_type WHERE id_project=',num2str(app.id_project));
%             data2=fetch(app.connection, query2);
%             
%             if height(data2)>0
%                 app.EntityTypeDropDown.Items=data2.name_type;
%             else
%                 entities=cell(1,1);
%                 entities{1,1}='<empty>';
%                 app.EntityTypeDropDown.Items=entities;
%             end
%            
        
           
        end

        % Value changed function: EntityTypeDropDown
        function EntityTypeDropDownValueChanged(app, event)
            app.value = app.EntityTypeDropDown.Value;
                
            % Tabella entity_type
            
            id_type=app.read_entity_type();
            
%             query2=strcat('SELECT * FROM entity_type WHERE name_type=''',value,'''', ' AND id_project=',string(app.id_project));
%             data2=fetch(app.connection, query2);
%             app.step_receivedTextArea.Value=data2.step_received(1);
%             app.step_computedTextArea.Value=data2.step_computed(1);
%             app.dynamic_computedTextArea.Value=data2.dynamic_computed(1);
%             app.step_sendedTextArea.Value=data2.step_sended(1);
%             app.entitytypeTextArea.Value=data2.name_type(1);
%             app.descriptionTextArea.Value=data2.description(1);
%             app.id_typeEditField.Value=num2str(data2.id_type(1));
%             app.colorEditField.Value=data2.color{1};
            
            
            % Tabella entity
            
            query3=strcat('SELECT * FROM entity WHERE id_type=',num2str(id_type), ' AND id_project=',string(app.id_project));
            data3=fetch(app.connection, query3);
            if height(data3)>0
                app.chooseDropDown.Items=data3.name_entity;
                if height(data3)>0
                    app.chooseDropDown.ValueChangedFcn(app,event);
                end
            end
            
            app.colorEditFieldValueChanged();


        end

        % Callback function
        function LoadLayersButtonPushed(app, event)
            query=strcat('select id_layer, a.id_res, color_res, name_res, dim_res, name_dep from layers as a join resources as b on a.id_res=b.id_res  where id_project=', string(app.id_project),' order by a.id_layer');
            data=fetch(app.connection, query);
            app.UITable.Data=data;


        end

        % Button pushed function: LoadResourcesButton
        function LoadResourcesButtonPushed(app, event)
        
        
            query=strcat(['select * from resources where id_project='''  num2str(app.id_project)  ''' order by name_res']);
            data=fetch(app.connection, query);
            app.num_resources=height(data);
            
            if app.num_resources>0           
                app.UITable2.Data=data;
            end
            
            app.res_deleted=0;
            app.UITable6.Data=[];
            
            app.UpdatetableButton.BackgroundColor='0.96, 0.96, 0.96';
            
            app.res_rand=floor(rand*10000);
            query=strcat('UPDATE design_flags SET VALUE=',string(app.res_rand),' WHERE name_flag=''res_flag''');
            execute(app.connection,query);
            
            
        end

        % Button pushed function: AddarowButton
        function AddarowButtonPushed(app, event)
            app.num_resources=app.num_resources+1;
            
            if app.num_resources==1
                app.UITable2.Data=table(1,{'<insert name>'},1,{'<insert dependency>'},{'#000000'},app.id_project,{' '});
            else
                app.UITable2.Data{app.num_resources,1}=0;
                app.UITable2.Data{app.num_resources,2}={'<insert name>'};
                app.UITable2.Data{app.num_resources,3}=1;
                app.UITable2.Data{app.num_resources,4}={' '};
                app.UITable2.Data{app.num_resources,5}={'<insert dependency>'};
                app.UITable2.Data{app.num_resources,6}={'#000000'};
                app.UITable2.Data{app.num_resources,7}=app.id_project;
                app.UITable2.Data{app.num_resources,8}={' '};
                
            end
            
            app.UpdatetableButton.BackgroundColor='1.00,0.00,0.00';
            
            
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            
            l=height(app.UITable2.Data);
            i=app.indices(1,1);
            
            if isempty(app.UITable6.Data)
                app.UITable6.Data=table(0,{''},0);
            end
            
            app.res_deleted=app.res_deleted+1;
            app.UITable6.Data(app.res_deleted,1)=app.UITable2.Data(i,1);
            app.UITable6.Data(app.res_deleted,2)=app.UITable2.Data(i,2);
            app.UITable6.Data(app.res_deleted,3)=app.UITable2.Data(i,7);
            
        
            % delete 
            if i==1
                table3=app.UITable2.Data(2:l,:);
            elseif i==l
                table3=app.UITable2.Data(1:l-1,:);
            else
                table1=app.UITable2.Data(1:i-1,:);
                table2=app.UITable2.Data(i+1:l,:);
                table3=[table1 ; table2];
            end
            
            app.indices=app.indices-1;            
            app.UITable2.Data=table3;
            
            app.UpdatetableButton.BackgroundColor='1.00,0.00,0.00';


            %%
            %% BISOGNA CANCELLARE LA RISORSA DA entity_port
            %%
            
            
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            index = event.Indices;
            app.indices=index(1,1);
            
            
            id_res=app.UITable2.Data{app.indices,1};
            query=strcat('SELECT b.name_entity, b.id_type, a.type_port from entity_port AS a JOIN  entity AS b ON  (a.id_entity=b.id_entity)  where  a.id_res=',string(id_res),' AND a.id_project=',string(app.id_project),' order BY b.name_entity');
            data_entities=fetch(app.connection,query);
            
            app.UITable2_2.Data=[];
            if height(data_entities)>0
                app.UITable2_2.Data=data_entities;    
            end


        end

        % Button pushed function: LoadVariablesButton
        function LoadVariablesButtonPushed(app, event)
            query=strcat('select * from variables where id_project=',num2str(app.id_project),' order by name_var');
            data=fetch(app.connection, query);
            app.num_variables=height(data);
            
            if app.num_variables>0
                app.UITable3.Data=data;
            end
            
            app.var_deleted=0;
            app.UITable5.Data=[];
            
            app.UpdateTableButton.BackgroundColor='0.96, 0.96, 0.96';
            
            app.last_variable=0;
            
            app.var_rand=floor(rand*10000);
            query=strcat('UPDATE design_flags SET VALUE=',string(app.var_rand),' WHERE name_flag=''var_flag''');
            execute(app.connection,query);
            
        end

        % Button pushed function: LoadLinksButton
        function LoadLinksButtonPushed(app, event)
            query=strcat('select * from dynamic_links where id_project=',string(app.id_project),' order by id_link');
            data=fetch(app.connection, query);
            app.num_links=height(data);
            if app.num_links>0
                app.UITable4.Data=data;
            end
            
            app.link_deleted=0;
            app.UITable7.Data=[];
            
            app.UpdateTableButton_2.BackgroundColor='0.96, 0.96, 0.96';
            
        end

        % Button pushed function: UpdatetableButton
        function UpdatetableButtonPushed(app, event)
        
             query=strcat('SELECT value FROM design_flags WHERE name_flag=''res_flag''');
             data=fetch(app.connection,query);
             
             if app.res_rand~=data.value(1)
                 f = errordlg('Please reload resources before updating');
                 
             else
        
        
                app.UpdatetableButton.BackgroundColor='0.96,0.96,0.96';
                l=size(app.UITable2.Data,1);
                query=strcat('DELETE FROM resources WHERE id_project=',string(app.id_project),'');
                %disp('delete')
                execute(app.connection,query);
                if l>0
                    for i=1:l
                        id_res=string(app.UITable2.Data{i,1});
                        name_res=app.UITable2.Data{i,2};
                        dim_res=string(app.UITable2.Data{i,3});
                        default_val=app.UITable2.Data{i,4};
                        name_dep=app.UITable2.Data{i,5};
                        color=app.UITable2.Data{i,6};
                        %id_project=string(UITable2.Data{i,6});
                        description=app.UITable2.Data{i,8};
                        query=strcat('INSERT INTO resources (id_res,name_res,dim_res,default_val,name_dep,color,id_project, description) VALUES (', id_res ,',''' ,name_res ,''',', dim_res,',''',default_val,''',''',name_dep ,''',''',color,''',',string(app.id_project),',''',description,''')');
                        %disp(query)
                        execute(app.connection,query)
                    end
                end
                
                app.LoadResourcesButtonPushed();
                
                errordlg('Please execute a Clean');
                app.CleanPortsButton.BackgroundColor='1.00,0.00,0.00';


                query=strcat('SELECT * FROM resources WHERE id_project=',num2str(app.id_project),' ORDER BY name_res');
                data6=fetch(app.connection, query);

                if height(data6)>0
                    app.chooseinputDropDown.Items=data6.name_res;
                    app.chooseoutputDropDown.Items=data6.name_res;
                
                    chooseinputDropDownValueChanged(app);
                    chooseoutputDropDownValueChanged(app);
                
                end
            
             end


        end

        % Button pushed function: AddarowButton_2
        function AddarowButton_2Pushed(app, event)
        
            f = listdlg('PromptString','Which kind of variable?','SelectionMode','single','ListString',{'NUMERIC','CONTINUOUS','NUMERIC_ARRAY','STRING'});
            
            figure(app.CISIAproDesignUIFigure)
            
                        
            switch f
                case []
                    var_type='';    
                case 1
                    var_type='NUMERIC';
                case 2
                    var_type='CONTINUOUS';
                case 3
                    var_type='NUMERIC_ARRAY';
                case 4
                    var_type='STRING';
            end
            
            if ~strcmp(var_type,'')
               
                app.num_variables=app.num_variables+1;
                
                if app.last_variable==0
                    new_variable=app.new_variable();
                    app.last_variable=new_variable;
                else
                    app.last_variable=app.last_variable+1;
                    new_variable=app.last_variable;
                end
                
                if app.num_variables==1
                    app.UITable3.Data=table(new_variable,{'<insert name>'},var_type,1,app.id_project,{' '});
                    
                else
                    
                     app.UITable3.Data{app.num_variables,1}=new_variable;
                     app.UITable3.Data{app.num_variables,2}={'<insert name>'};
                     app.UITable3.Data{app.num_variables,3}={var_type};
                     app.UITable3.Data{app.num_variables,4}=1;
                     app.UITable3.Data{app.num_variables,5}=app.id_project;
                     app.UITable3.Data{app.num_variables,6}={' '};


                end
                
                app.UpdateTableButton.BackgroundColor='1.00,0.00,0.00';
            
            end
            
        end

        % Button pushed function: DeleteSelectedButton_2
        function DeleteSelectedButton_2Pushed(app, event)
            % delete 
            l=height(app.UITable3.Data);
            i=app.indices(1,1);
            
            if isempty(app.UITable5.Data)
                app.UITable5.Data=table(0,{''},0);
            end
            
            app.var_deleted=app.var_deleted+1;
            app.UITable5.Data(app.var_deleted,1)=app.UITable3.Data(i,1);
            app.UITable5.Data(app.var_deleted,2)=app.UITable3.Data(i,2);
            app.UITable5.Data(app.var_deleted,3)=app.UITable3.Data(i,5);
            
            if i==1
                table3=app.UITable3.Data(2:l,:);
            elseif i==l
                table3=app.UITable3.Data(1:l-1,:);
            else
                table1=app.UITable3.Data(1:i-1,:);
                table2=app.UITable3.Data(i+1:l,:);
                table3=[table1 ; table2];
            end
            
            app.indices=app.indices-1;            
            app.UITable3.Data=table3;
            
            app.UpdateTableButton.BackgroundColor='1.00,0.00,0.00';
        end

        % Cell selection callback: UITable3
        function UITable3CellSelection(app, event)
            app.indices = event.Indices;
            i=app.indices(1,1);
            id_var=app.UITable3.Data{i,1};
            query=strcat('SELECT c.name_entity, c.id_type from status AS a JOIN variables AS b JOIN  entity AS c ON  (a.id_var=b.id_var AND a.id_entity=c.id_entity)  where  b.id_var=',string(id_var),' AND a.id_project=',string(app.id_project),' order BY c.name_entity');
            data_entities=fetch(app.connection,query);
            
            app.UITable3_2.Data=[];
            if height(data_entities)>0
                app.UITable3_2.Data=data_entities;    
            end


        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            
             query=strcat('SELECT value FROM design_flags WHERE name_flag=''var_flag''');
             data=fetch(app.connection,query);


             if app.var_rand~=data.value(1)
                 f = errordlg('Please reload variables before updating');
                 
             else
        
                 app.UpdatetableButton.BackgroundColor='0.96,0.96,0.96';
                 l=size(app.UITable3.Data,1);
                 query=strcat('DELETE FROM variables WHERE id_project=',string(app.id_project),'');
                 execute(app.connection,query);
                 if l>0
                     for i=1:l
                         id_var=string(app.UITable3.Data{i,1});
                         name_var=app.UITable3.Data{i,2};
                         val_type=app.UITable3.Data{i,3};
                         var_dim=string(app.UITable3.Data{i,4});
                         description=app.UITable3.Data{i,6};
    
                         query=strcat('INSERT INTO variables (id_var,name_var,val_type,var_dim,id_project,description) VALUES (', id_var ,',''' ,name_var, ''',''',val_type,''',',var_dim,',',string(app.id_project),',''',description,''')');
                         execute(app.connection,query)
                     end
                 end
                
                % cancellazione delle variabili nella UITable5 dalla tabella
                % status
                l=size(app.UITable5.Data,1);
                if l>0
                    for i=1:l
                         id_var=string(app.UITable5.Data{i,1});
                         
                         query=strcat('DELETE FROM status WHERE id_var=',string(id_var));
                         execute(app.connection,query)
                     end
                end
                
                app.LoadVariablesButtonPushed();


                query=strcat('SELECT * FROM variables WHERE id_project=',num2str(app.id_project), ' ORDER BY name_var');
                data7=fetch(app.connection, query);
                if height(data7)>0
                    app.choosevariableDropDown.Items=data7.name_var;
                end
            
            end
            
        end

        % Button pushed function: DeleteSelectedButton_3
        function DeleteSelectedButton_3Pushed(app, event)
            % delete 
            l=height(app.UITable4.Data);
            i=app.indices(1,1);
            
            if isempty(app.UITable7.Data)
                app.UITable7.Data=table(0,{''},0);
            end
            
            app.link_deleted=app.link_deleted+1;
            app.UITable7.Data(app.link_deleted,1)=app.UITable4.Data(i,1);
            app.UITable7.Data(app.link_deleted,2)=app.UITable4.Data(i,2);
            app.UITable7.Data(app.link_deleted,3)=app.UITable4.Data(i,4);
            
            if i==1
                table3=app.UITable4.Data(2:l,:);
            elseif i==l
                table3=app.UITable4.Data(1:l-1,:);
            else
                table1=app.UITable4.Data(1:i-1,:);
                table2=app.UITable4.Data(i+1:l,:);
                table3=[table1 ; table2];
            end
            
            app.indices=app.indices-1;            
            app.UITable4.Data=table3;
            
            app.UpdateTableButton_2.BackgroundColor='1.00,0.00,0.00';


        end

        % Cell selection callback: UITable4
        function UITable4CellSelection(app, event)
            app.indices = event.Indices;
        end

        % Button pushed function: AddarowButton_3
        function AddarowButton_3Pushed(app, event)
            
            app.num_links=app.num_links+1;
        
            if app.num_links==1
                app.UITable4.Data=table(app.new_link(),{'<insert name>'},1,app.id_project);
            else
                app.UITable4.Data{app.num_links,1}=app.new_link();
                app.UITable4.Data{app.num_links,2}={'<insert name>'};
                app.UITable4.Data{app.num_links,3}=1;
                app.UITable4.Data{app.num_links,4}=app.id_project;
            end
            
            app.UpdateTableButton_2.BackgroundColor='1.00,0.00,0.00';
        end

        % Value changed function: chooseinputDropDown
        function chooseinputDropDownValueChanged(app, event)
            app.name_res = app.chooseinputDropDown.Value;
            query=strcat('SELECT * FROM resources WHERE name_res=''',app.name_res,''' AND id_project=''',num2str(app.id_project)''', '''');
            data=fetch(app.connection, query);
            app.id_res_in=data.id_res(1);
            
        end

        % Button pushed function: AddInputButton
        function AddInputButtonPushed(app, event)
        
            if num2str(app.id_res_in)>0    
        
            % verifica che l'input non esista
            query=strcat('SELECT * FROM entity_port WHERE id_res=',num2str(app.id_res_in), ' AND type_port=''INPUT'' AND id_entity=', num2str(app.id_entity),' AND id_project=''',num2str(app.id_project)''', '''');
            data=fetch(app.connection, query);
        
            if height(data)>0
                f = errordlg('Input already exist');
                
            else
                            
                % per tutte le id_entity di entity_type
                
                query=strcat('SELECT * FROM entity WHERE id_type=',num2str(app.id_type),' AND id_project=',num2str(app.id_project));
                data=fetch(app.connection, query);
                
                for i=1:height(data)
                    app.id_port=app.new_port();
                    query=strcat('INSERT INTO entity_port (id_port, id_entity, type_port, id_res, id_project) VALUES (', num2str(app.id_port),',' ,num2str(data.id_entity(i,1)), ',''INPUT'',', num2str(app.id_res_in),',',string(app.id_project) ,')');
                    execute(app.connection,query)                
                end
                
                query=strcat('SELECT name_res, dim_res FROM resources AS a JOIN  entity_port AS b  ON a.id_res=b.id_res  WHERE id_entity=''',string(app.id_entity),''' AND type_port=''INPUT''');
                data3=fetch(app.connection, query);
                num_inputs=height(data3);
                app.UITable8.Data=[];
                app.UITable8.Data=data3;
                
            end
            
            end


        end

        % Button pushed function: RemoveInputButton
        function RemoveInputButtonPushed(app, event)
            
            if size(app.UITable8.Data,1)>0
            
            i=app.indices(1,1);    
            
            
            app.name_res=cell2mat(app.UITable8.Data{i,1});
            query=strcat('SELECT * FROM resources WHERE name_res=''',app.name_res,''' AND id_project=',num2str(app.id_project));
            data=fetch(app.connection, query);
            app.id_res=data.id_res(1);
            
            query=strcat('SELECT * FROM entity WHERE id_type=',num2str(app.id_type),' AND id_project=',num2str(app.id_project));
            data=fetch(app.connection, query);
            
            % per tutte le id_entity di id_type
            
            for i=1:height(data)
                query=strcat('DELETE FROM entity_port WHERE id_entity=', num2str(data.id_entity(i,1)),' AND type_port=''INPUT'' AND id_res=', num2str(app.id_res) ,' AND id_project=',num2str(app.id_project));
                execute(app.connection,query) 
            end
            
            query=strcat('SELECT name_res, dim_res FROM resources AS a JOIN  entity_port AS b  ON a.id_res=b.id_res  WHERE id_entity=''',string(app.id_entity),''' AND type_port=''INPUT''');
            data3=fetch(app.connection, query);
            app.UITable8.Data=data3;
            
            end
        
        
        end

        % Button pushed function: RemoveOutputButton
        function RemoveOutputButtonPushed(app, event)
        
            if size(app.UITable9.Data)>0
                
            
            i=app.indices(1,1);    
            app.name_res=cell2mat(app.UITable9.Data{i,1});
            query=strcat('SELECT * FROM resources WHERE name_res=''',app.name_res,''' AND id_project=',num2str(app.id_project));
            data=fetch(app.connection, query);
            app.id_res=data.id_res(1);
            
            query=strcat('SELECT * FROM entity WHERE id_type=',num2str(app.id_type),' AND id_project=',num2str(app.id_project));
            data=fetch(app.connection, query);
            
            % per tutte le id_entity di id_type
            
            for i=1:height(data)
                query=strcat('DELETE FROM entity_port WHERE id_entity=', num2str(data.id_entity(i,1)),' AND type_port=''OUTPUT'' AND id_res=', num2str(app.id_res) ,' AND id_project=',num2str(app.id_project));
                execute(app.connection,query) 
            end
            
            query=strcat('SELECT name_res, dim_res FROM resources AS a JOIN  entity_port AS b  ON a.id_res=b.id_res  WHERE id_entity=''',string(app.id_entity),''' AND type_port=''OUTPUT''');
            data3=fetch(app.connection, query);
            app.UITable9.Data=data3;
            
            end
            
        end

        % Cell selection callback: UITable8
        function UITable8CellSelection(app, event)
            app.indices = event.Indices;
            i=app.indices(1,1);    
            j=app.indices(1,2);
            val=app.UITable8.Data{i,j};
            clipboard('copy',val{1});
        end

        % Cell selection callback: UITable9
        function UITable9CellSelection(app, event)
            app.indices = event.Indices;
            i=app.indices(1,1);    
            j=app.indices(1,2);
            val=app.UITable9.Data{i,j};
            clipboard('copy',val{1});
        end

        % Cell selection callback: UITable10
        function UITable10CellSelection(app, event)
            app.indices = event.Indices;
            i=app.indices(1,1);    
            j=app.indices(1,2);
            val=app.UITable10.Data{i,j};
            clipboard('copy',val{1});
            
        end

        % Button pushed function: AddOutputButton
        function AddOutputButtonPushed(app, event)
            
            if num2str(app.id_res_out)>0   
        
            % verifica che l'output non esista
            query=strcat('SELECT * FROM entity_port WHERE id_res=',num2str(app.id_res_out), ' AND type_port=''OUTPUT'' AND id_entity=', num2str(app.id_entity),' AND id_project=''',num2str(app.id_project)''', '''');
            data=fetch(app.connection, query);
        
            if height(data)>0
                f = errordlg('Output already exist');
                
            else
                            
                % per tutte le id_entity di entity_type
                
                query=strcat('SELECT * FROM entity WHERE id_type=',num2str(app.id_type),' AND id_project=',num2str(app.id_project));
                data=fetch(app.connection, query);
                
                for i=1:height(data)
                    app.id_port=app.new_port();
                    query=strcat('INSERT INTO entity_port (id_port, id_entity, type_port, id_res, id_project) VALUES (', num2str(app.id_port),',' ,num2str(data.id_entity(i,1)), ',''OUTPUT'',', num2str(app.id_res_out),',',string(app.id_project) ,')');
                    execute(app.connection,query)                
                end
                
                query=strcat('SELECT name_res, dim_res FROM resources AS a JOIN  entity_port AS b  ON a.id_res=b.id_res  WHERE id_entity=''',string(app.id_entity),''' AND type_port=''OUTPUT''');
                data3=fetch(app.connection, query);
                num_inputs=height(data3);
                app.UITable9.Data=[];
                app.UITable9.Data=data3;
                
            end
            end
            
        end

        % Value changed function: chooseoutputDropDown
        function chooseoutputDropDownValueChanged(app, event)
            app.name_res = app.chooseoutputDropDown.Value;
            query=strcat('SELECT * FROM resources WHERE name_res=''',app.name_res,''' AND id_project=''',num2str(app.id_project)''', '''');
            data=fetch(app.connection, query);
            app.id_res_out=data.id_res(1);
        end

        % Callback function
        function Button_6Pushed(app, event)


            if app.id_project>0
                app.TabGroup.SelectedTab = app.RadialDiffusionTab;
            else
                if app.connected
                    f = errordlg('Please select a project');
                    
                else
                    f = errordlg('Please open a conenction');
                    
                end
            end
            
        end

        % Button pushed function: LoadRadialButton
        function LoadRadialButtonPushed(app, event)
            query=strcat('select * from radial_spread where id_project=',string(app.id_project),' order by id_radial');
            data=fetch(app.connection, query);
            app.num_radial=height(data);
            if app.num_radial>0
                app.UITable11.Data=data;
            end
            
            app.radial_deleted=0;
            app.UITable12.Data=[];
            
            app.UpdateTableButton_3.BackgroundColor='0.96, 0.96, 0.96';
            
        end

        % Display data changed function: UITable10
        function UITable10DisplayDataChanged(app, event)
            newDisplayData = app.UITable10.DisplayData;
            app.UpdatestatusButton.BackgroundColor='1, 0, 0';
        end

        % Button pushed function: UpdatestatusButton
        function UpdatestatusButtonPushed(app, event)
            
            app.UpdatestatusButton.BackgroundColor='0.96,0.96,0.96';
            % Per ogni id_var delle variabili della tabella 10
            
            l=size(app.UITable10.Data,1);
            if l>0
                for i=1:l
                    name_var=app.UITable10.Data{i,1};
                    status=string(app.UITable10.Data{i,4});
                    query=strcat('SELECT * FROM variables WHERE name_var=''',name_var,''' AND id_project=',string(app.id_project));
                    data=fetch(app.connection, query);
                    id_var=data.id_var(1);
                    
                    query=strcat('UPDATE status  SET val_status=''',status, ''' WHERE id_var=', string(id_var), ' AND id_entity=', string(app.id_entity), ' AND id_project=', string(app.id_project));
                    execute(app.connection,query)
                    
                    
                end
                
                a=app.read_state_variables();
                
            end
        
        
        end

        % Button pushed function: AddVariableButton
        function AddVariableButtonPushed(app, event)

            %check if name_var exist in 
            %app.UITAble10

            %size(app.UITable10.Data)
            if ~isempty(app.UITable10.Data)
                rowIdx = strcmp(app.UITable10.Data{:,1}, app.choosevariableDropDown.Value);
                %find(rowIdx)
                if not(isempty(find(rowIdx)))
                    errordlg('Variable already included')
                    return
                end
            end


            % recupero id_var
            query=strcat('SELECT * FROM variables WHERE name_var=''',app.choosevariableDropDown.Value,'''');
            data=fetch(app.connection,query);
            id_var=data.id_var(1);
            val_type=data.val_type(1);
            
        
            % per tutte le entità di tipo id_entity va aggiunta la variabile
            % id_var
            
            query=strcat('SELECT * FROM entity WHERE id_type=''',string(app.id_type),''' AND id_project=',string(app.id_project));
            data=fetch(app.connection,query);
            l=height(data);


            for i=1:l
                query=strcat('INSERT INTO status (id_entity, id_var, val_type, val_status, id_project) VALUES (',string(data.id_entity(i)),',',string(id_var),',''',val_type,''', '''', ',string(app.id_project),')');
                execute(app.connection,query)
            end
                    
            a=app.read_state_variables();
        
        
        end

        % Button pushed function: RemoveVarButton
        function RemoveVarButtonPushed(app, event)
            
            % recupero il nome della variabile
            i=app.indices(1,1);    
            name_var=app.UITable10.Data(i,1);
           
            
            % recupero id_var
            query=strcat('SELECT * FROM variables WHERE name_var=''',name_var.name_var{1},'''');
            data=fetch(app.connection,query);
            id_var=data.id_var(1);
            
            
            % recupero tutte le id_entity 
            query=strcat('SELECT * FROM entity WHERE id_type=''',string(app.id_type),''' AND id_project=',string(app.id_project));
            data=fetch(app.connection,query);
            l=height(data);
            
            for i=1:l
                query=strcat('DELETE FROM status WHERE id_entity=',string(data.id_entity(i)),' AND id_var=',string(id_var), ' AND id_project=',string(app.id_project));
                execute(app.connection,query)
            end
            
            a=app.read_state_variables();
        end

        % Button pushed function: updateEntityTypebehaviorButton
        function updateEntityTypebehaviorButtonPushed(app, event)
            
            if strcmp(app.entitytypeTextArea.Value,'')
                f = errordlg('Please choose an Entity Type');
                
                
            else
                % update of table entity_type    
                app.entitytypeTextArea.Value=strrep(app.entitytypeTextArea.Value,' ','');
                
                app.cell_array=app.entitytypeTextArea.Value;
                et=app.convert_to_string();
                
                app.cell_array=app.step_receivedTextArea.Value;
                sr=app.convert_to_string();
                
                app.cell_array=app.step_computedTextArea.Value;
                sc=app.convert_to_string();
                
                app.cell_array=app.dynamic_computedTextArea.Value;
                dc=app.convert_to_string();
                
                app.cell_array=app.step_sendedTextArea.Value;
                ss=app.convert_to_string();
                
                app.cell_array=app.descriptionTextArea.Value;
                de=app.convert_to_string();
                
                app.cell_array={app.colorEditField.Value};
                co=app.convert_to_string();
                
                
                query=strcat('UPDATE entity_type SET name_type=''',et,''',  step_received=''', sr,''', step_computed=''',sc , ''', dynamic_computed=''',dc , ''', step_sended=''', ss, ''', description=''', de,''', color=''', co,''' WHERE id_type=',string(app.id_type), ' AND id_project=', string(app.id_project));
                execute(app.connection,query)
            end
            
        end

        % Button pushed function: DeleteEntityButton
        function DeleteEntityButtonPushed(app, event)
            % verifica che il nome esista in maniera univoca
            query=strcat('SELECT * FROM entity WHERE id_entity=''',string(app.id_entity),''' AND id_project=',string(app.id_project));
            data=fetch(app.connection,query);
            if height(data)==1

                answer = questdlg(strcat('Are you sure you want delete entity:',data.name_entity,'?'), 'Confirm','Yes','No','No');
                
                switch answer
                    case 'Yes'
                        query=strcat('DELETE FROM entity WHERE id_entity=',string(app.id_entity),' AND id_project=',string(app.id_project));
                        execute(app.connection,query)
                        
                        
                        query3=strcat('SELECT * FROM entity WHERE id_type=',num2str(app.id_type), ' AND id_project=',string(app.id_project));
                        data3=fetch(app.connection, query3);
                        app.chooseDropDown.Items=data3.name_entity;
                        if height(data3)>0
                            app.chooseDropDown.ValueChangedFcn(app,event);
                        end
                
                        
    %                     a=app.read_state_variables();
    %                     a=app.read_inputs();
    %                     a=app.read_outputs();
                        
                        
                    case 'No'
                end

            else
                errordlg('Error: More Entities with the same id');
            end
        end

        % Button pushed function: newemptyEntityTypeButton
        function newemptyEntityTypeButtonPushed(app, event)
            if strcmp(app.nameEditField.Value,'')
                f = errordlg('Please specify a name for entity type'); 
                
            else
                id_type=app.new_id_type;
                query=strcat('INSERT INTO entity_type (id_type, name_type, id_project, color, step_received, step_computed, dynamic_computed, step_sended, description) VALUES (',string(id_type),', ''',app.nameEditField.Value,''',',string(app.id_project),', ''#FFFFFF'','''','''','''','''','''')');
                execute(app.connection,query)
                
                query2=strcat('SELECT * FROM entity_type WHERE id_project=',num2str(app.id_project),' ORDER by name_type ASC');
                data2=fetch(app.connection, query2);
                app.EntityTypeDropDown.Items=data2.name_type;
                app.EntityTypeDropDown.Value=app.nameEditField.Value;
                app.typeDropDown.Items=data2.name_type;
                app.typeDropDown.Value=app.nameEditField.Value;
                
                app.EntityTypeDropDown.ValueChangedFcn(app,event);
            
                
            end
        
        
        end

        % Button pushed function: newEntityofEntityTypeButton
        function newEntityofEntityTypeButtonPushed(app, event)


            if strcmp(app.nameEditField.Value,'')
                f = errordlg('Please specify a name for entity');    
                
            elseif strcmp(app.typeDropDown.Value,'')
                f = errordlg('Please specify an entity type');    
                
            else
                
                % cerca l'id_type dall name_type
                query2=strcat('SELECT * FROM entity_type WHERE name_type=''',app.typeDropDown.Value,''' AND id_project=',string(app.id_project));
                data2=fetch(app.connection, query2);
                id_type=data2.id_type(1);
                
                % verifica che il nome non esista già
                query=strcat('SELECT * FROM entity WHERE name_entity=''',app.nameEditField.Value, ''' AND id_project=',string(app.id_project));
                data=fetch(app.connection, query);
                if height(data)>0
                    f = errordlg('Name already exist whitin this project');
                    
                else
                    id_entity=app.new_entity();
                    query=strcat('INSERT INTO entity (id_entity, id_project, id_type, name_entity) VALUES (',string(id_entity) ,',',string(app.id_project),',' ,string(id_type) ,',''',app.nameEditField.Value, ''')');
                    execute(app.connection,query)
                    
                    
                    % verifico se ci sono altre entità dello stesso tipo
                    query=strcat('SELECT * from entity WHERE id_type=',string(id_type), ' AND id_entity<>',string(id_entity) ,' AND id_project=',string(app.id_project));
                    data=fetch(app.connection,query);
                    
                    if height(data)>0 % esistono altre entità dello steso tipo
                        id_entity2=data.id_entity(1);
                    
                        
                        % verifico se ci sono variabili di stato assegnate
                        % all'entità selezionata
                        query=strcat('SELECT DISTINCT id_var,val_type FROM status WHERE id_entity=',string(id_entity2),' AND id_project=',string(app.id_project));
                        data2=fetch(app.connection,query);
                        
                        %disp(data2)
                        
                        if height(data2)>0 % esistono altre variabili
                            for i=1:height(data2)
                                query=strcat('INSERT INTO status (id_entity,id_var,val_type,val_status,id_project) VALUES(',string(id_entity),',',string(data2.id_var(i)),',''',data2.val_type(i),''',','''''',',',string(app.id_project),')');
                                execute(app.connection,query);
                            end
                            
                        end
                        
                        
                        % verifico se ci sono input o output da assegnare
                        query=strcat('SELECT type_port,id_res FROM entity_port WHERE id_entity=',string(id_entity2),' AND id_project=',string(app.id_project));
                        data3=fetch(app.connection,query);
                        
                        if height(data3)>0 % esistono porte di input e/o output
                            for i=1:height(data3)
                                query=strcat('INSERT INTO entity_port (id_port, id_entity,type_port, id_res,id_project) VALUES(NULL,',string(id_entity),',''',string(data3.type_port(i)),''',',string(data3.id_res(i)),',',string(app.id_project),')');
                                execute(app.connection,query);
                            end
                            
                        end


                    else % is the first entity of this entity type
                        query=strcat('INSERT INTO status (id_entity,id_var,val_type,val_status,id_project) VALUES(',string(id_entity),',1,''NUMERIC'',1,',string(app.id_project),')');
                        execute(app.connection,query);
                           
                    end


                    % ricarica l'entità per editarla
                     query3=strcat('SELECT * FROM entity WHERE id_type=',num2str(id_type), ' AND id_project=',string(app.id_project));
                     data3=fetch(app.connection, query3);
                     app.chooseDropDown.Items=data3.name_entity;
                     app.chooseDropDown.Value=app.nameEditField.Value;
                     app.chooseDropDown.ValueChangedFcn(app,event);
                    
                    
                end
                
            end
        end

        % Button pushed function: UpdateTableButton_2
        function UpdateTableButton_2Pushed(app, event)
            
        end

        % Button pushed function: duplicateEntityTypeButton
        function duplicateEntityTypeButtonPushed(app, event)

            % recupera_id_project
            query=strcat('SELECT * FROM projects WHERE name_project=''',app.insidetheprojectDropDown.Value,'''');
            data=fetch(app.connection,query);
            new_id_project=string(data.id_project);
            
            % recupera id_entity
            query=strcat('SELECT * FROM entity_type WHERE id_type=',string(app.id_type),' AND id_project=',string(app.id_project));
            data=fetch(app.connection,query);
            
            new_id_type=num2str(app.new_id_type());
            
            query=strcat('INSERT INTO entity_type (id_type, name_type, id_project, color, step_received, step_computed, dynamic_computed, step_sended, description) SELECT +', new_id_type, ', name_type,',  new_id_project,  ', color, step_received, step_computed, dynamic_computed, step_sended, description FROM entity_type WHERE id_type =', string(app.id_type));
            

            execute(app.connection,query)


        end

        % Button pushed function: NewEmptyProjectButton
        function NewEmptyProjectButtonPushed(app, event)
            if strcmp(app.nameEditField_3.Value,'')
                f = errordlg('Choose a name for the project');
                
            else
                query=strcat('INSERT INTO projects (id_project, name_project) VALUES (NULL,''',app.nameEditField_3.Value ,''')');
                execute(app.connection,query)
            
            end
             
        end

        % Button pushed function: AddarowButton_4
        function AddarowButton_4Pushed(app, event)
            
        end

        % Value changed function: choosecatalogDropDown
        function choosecatalogDropDownValueChanged(app, event)
            value = app.choosecatalogDropDown.Value;
            
            if app.connected
            sqlquery=['Use ' value];
            try
                execute(app.connection,sqlquery);
            catch
                f = errordlg('Catalog not opened!');
                
                app.catalog=false;
            end
            
            % salva i parametri di connessione per il modello
            connection.databasename = '';
            connection.user=app.UserNameDropDown.Value;
            connection.password=app.PasswordEditField.Value;
            connection.driver = 'com.mysql.cj.jdbc.Driver';
            connection.url = ['jdbc:mysql://' app.urlDropDown.Value '/?serverTimezone=UTC'];
            connection.schema=value;
            save('cisia_init.mat','connection') 
            
            app.last_catalog=app.choosecatalogDropDown.Value;
            
        end
        
            
        end

        % Button pushed function: ColorpickerButton
        function ColorpickerButtonPushed(app, event)
        
            if strcmp(app.colorEditField.Value,'')
                color=[1 1 1];
            else
                col=app.colorEditField.Value;
                color=[hex2dec(col(2:3)) hex2dec(col(4:5)) hex2dec(col(6:7))]/255;
            end
        
            color=uisetcolor(color);
            figure(app.CISIAproDesignUIFigure)
            if size(color,2)==3
                app.ColorpickerButton.BackgroundColor=color;
            end
            
            
            a=dec2hex(floor(color*255));
            app.colorEditField.Value=['#' a(1,:) a(2,:) a(3,:)];
        end

        % Value changed function: colorEditField
        function colorEditFieldValueChanged(app, event)
            value = app.colorEditField.Value;
            
            color=[hex2dec(value(2:3)) hex2dec(value(4:5)) hex2dec(value(6:7))]/255;
            
            if size(color,2)==3
                app.ColorpickerButton.BackgroundColor=color;
            end
            
        end

        % Callback function
        function Button_7Pushed(app, event)
            
            if app.id_project>0
                app.TabGroup.SelectedTab = app.Save2DBTab;
            else
                if app.connected
                    f = errordlg('Please select a project');
                    
                else
                    f = errordlg('Please open a conenction');
                    
                end
            end
                        
        end

        % Button pushed function: LoadSave2DBButton
        function LoadSave2DBButtonPushed(app, event)
            query=strcat('SELECT id_save2db, save_name, name_res, b.id_res FROM save2db AS a JOIN resources AS b WHERE a.id_res=b.id_res AND a.id_project=',string(app.id_project),' order by a.id_save2db');
            data=fetch(app.connection, query);
            app.num_save2db=height(data);
            if app.num_save2db>0
                app.UITable13.Data=data;
            end
            app.save2db_deleted=0;
            app.last_save2db=0;
            
            app.UITable14.Data=[];
            
            app.UpdateTableButton_5.BackgroundColor='0.96, 0.96, 0.96';
        end

        % Button pushed function: ColorpickerButton_2
        function ColorpickerButton_2Pushed(app, event)
            if strcmp(app.colorEditField_2.Value,'')
                color=[1 1 1];
            else
                col=app.colorEditField_2.Value;
                color=[hex2dec(col(2:3)) hex2dec(col(4:5)) hex2dec(col(6:7))]/255;
            end
        
            color=uisetcolor(color);
            a=dec2hex(floor(color*255));
            app.colorEditField_2.Value=['#' a(1,:) a(2,:) a(3,:)];
            app.colorEditField_2ValueChanged();
        end

        % Value changed function: colorEditField_2
        function colorEditField_2ValueChanged(app, event)
            value = app.colorEditField_2.Value;
            
            color=[hex2dec(value(2:3)) hex2dec(value(4:5)) hex2dec(value(6:7))]/255;
            
            if size(color,2)==3
                app.ColorpickerButton_2.BackgroundColor=color;
            end
        end

        % Button pushed function: LoadRoutesButton
        function LoadRoutesButtonPushed(app, event)
            query=strcat('SELECT  id_route, a.id_routing, routing_name, c.name_res, dim_res, res_index, out_port FROM route_link AS a JOIN routing_table AS b JOIN resources AS c WHERE  a.id_project=b.id_project AND a.id_project=c.id_project AND b.name_res=c.name_res AND  a.id_routing=b.id_routing AND a.id_project=',string(app.id_project) ,' ORDER BY routing_name');
            data=fetch(app.connection, query);
            app.num_routes=height(data);
            app.UITable15.Data=[];
            if app.num_routes>0
                app.UITable15.Data=data;
            end
            
            
            app.route_deleted=0;
            app.UITable16.Data=[];
            
            app.last_routing=0;
            
            app.UpdateTableButton_6.BackgroundColor='0.96, 0.96, 0.96';
            
            
        end

        % Callback function
        function Button_8Pushed(app, event)
                        if app.id_project>0
                app.TabGroup.SelectedTab = app.RoutingTab;
            else
                if app.connected
                    f = errordlg('Please select a project');
                    
                else
                    f = errordlg('Please open a conenction');
                    
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if ~strcmp(selectedTab.Title,'Database')
                 if ~app.id_project>0
                    if app.connected
                        f = errordlg('Please select a project');
                        
                        app.TabGroup.SelectedTab=app.DatabaseTab;
                    else
                        f = errordlg('Please open a conenction');
                        
                        app.TabGroup.SelectedTab=app.DatabaseTab;
                    end
                    
                 else
                     % si può andare agli altri tab
                   switch selectedTab.Title
                        case 'Entity Maker'
                            app.TopImage.ImageSource='entity_maker3.png';
                        case 'Layers & Resources'
                            app.TopImage.ImageSource='layers_and_resources2.png';
                        case 'State Variables'
                            app.TopImage.ImageSource='state_variables2.png';
                        case 'Link State'
                            app.TopImage.ImageSource='link_state2.png';
                        case 'Radial Diffusion'
                            app.TopImage.ImageSource='icons8-cerchio-di-transizione-64.png';
                        case 'Save2DB'
                            app.TopImage.ImageSource='icons8-esportazione-database-64.png';
                        case 'Routing'
                            app.TopImage.ImageSource='icons8-multicast-64.png';
                       case 'Run Manager'
                            app.TopImage.ImageSource='icons8-flow-64 (1).png';
                        case 'Output Run'
                            app.TopImage.ImageSource='icons8-database-administrator-64.png';
                    end


                 end
            else
                app.TabGroup.SelectedTab=app.DatabaseTab;
                app.TopImage.ImageSource='icons8-aggiungi-database-64 (1).png';
            end
            
            
        end

        % Button pushed function: AddarowButton_6
        function AddarowButton_6Pushed(app, event)

            query=strcat('SELECT * from route_link AS a JOIN resources as b WHERE a.id_res=b.id_res AND a.id_project=',string(app.id_project));
            data=fetch(app.connection,query)
            
            f = listdlg('PromptString','For which router?','SelectionMode','single','ListString',data.routing_name)
            
            figure(app.CISIAproDesignUIFigure)
            
            if f>0
        
                
                app.num_routes=app.num_routes+1;
                
                if app.last_routing==0
                    new_routing=app.new_routing();
                    app.last_routing=new_routing;
                else
                    app.last_routing=app.last_routing+1;
                    new_routing=app.last_routing;
                end
                
                
                if app.num_routes==1
                    app.UITable15.Data=table(new_routing,data.id_routing(f),data.routing_name(f),data.name_res(f),data.dim_res(f),0,0);
                else
                    app.UITable15.Data{app.num_routes,1}=new_routing;
                    app.UITable15.Data{app.num_routes,2}=data.id_routing(f);  % id del router
                    app.UITable15.Data{app.num_routes,3}=data.routing_name(f);
                    app.UITable15.Data{app.num_routes,4}=data.name_res(f);
                    app.UITable15.Data{app.num_routes,5}=data.dim_res(f);
                    app.UITable15.Data{app.num_routes,6}=0;
                    app.UITable15.Data{app.num_routes,7}=0;
                    
                end
                
                app.UpdateTableButton_6.BackgroundColor='1.00,0.00,0.00';
            else
                disp('No router selected')
            
            end


        end

        % Button pushed function: LoadRoutersButton
        function LoadRoutersButtonPushed(app, event)
            query=strcat('SELECT a.id_routing, a.routing_name, c.name_res, a.id_res FROM route_link AS a JOIN resources AS c WHERE  a.id_project=c.id_project AND a.id_res=c.id_res AND a.id_project=',string(app.id_project) ,' ORDER BY routing_name');
            data=fetch(app.connection, query);
            
            
            app.num_route_link=height(data);
            if app.num_route_link>0
                app.UITable17.Data=data;
            end
            
            app.route_link_deleted=0;
            
            app.last_route_link=0;
            
            app.UITable18.Data=[];
            
            app.UpdateTableButton_7.BackgroundColor='0.96, 0.96, 0.96';
        end

        % Button pushed function: AddarowButton_7
        function AddarowButton_7Pushed(app, event)
            
            query=strcat('SELECT * from resources WHERE id_project=',string(app.id_project));
            data=fetch(app.connection,query);
            
            f = listdlg('PromptString','For which resource?','SelectionMode','single','ListString',data.name_res);
         
            figure(app.CISIAproDesignUIFigure)
            
            if f>0
        
                
                app.num_route_link=app.num_route_link+1;
                
                if app.last_route_link==0
                    new_route_link=app.new_route_link();
                    app.last_route_link=new_route_link;
                else
                    app.last_route_link=app.last_route_link+1;
                    new_route_link=app.last_route_link;
                end
                
                
                if app.num_route_link==1
                    app.UITable17.Data=table(new_route_link,{'<insert name>'},data.name_res(f));
                else
                    app.UITable17.Data{app.num_route_link,1}=new_route_link;
                    app.UITable17.Data{app.num_route_link,2}={'<insert name>'};
                    app.UITable17.Data{app.num_route_link,3}=data.name_res(f);
                    app.UITable17.Data{app.num_route_link,4}=data.id_res(f);
                    
                end
                
                app.UpdateTableButton_7.BackgroundColor='1.00,0.00,0.00';
            else
                disp('Nessuna risorsa selezionata')
            
            end
        
        end

        % Cell selection callback: UITable17
        function UITable17CellSelection(app, event)
            app.indices = event.Indices;
            
        end

        % Button pushed function: DeleteSelectedButton_7
        function DeleteSelectedButton_7Pushed(app, event)
            % delete 
            l=height(app.UITable17.Data);
            i=app.indices(1,1);
            
            if isempty(app.UITable18.Data)
                app.UITable18.Data=table(0,{''},0);
            end
            
            app.route_link_deleted=app.route_link_deleted+1;
            app.UITable18.Data(app.route_link_deleted,1)=app.UITable17.Data(i,1);
            app.UITable18.Data(app.route_link_deleted,2)=app.UITable17.Data(i,2);
            app.UITable18.Data(app.route_link_deleted,3)=app.UITable17.Data(i,4);
            
            if i==1
                table3=app.UITable17.Data(2:l,:);
            elseif i==l
                table3=app.UITable17.Data(1:l-1,:);
            else
                table1=app.UITable17.Data(1:i-1,:);
                table2=app.UITable17.Data(i+1:l,:);
                table3=[table1 ; table2];
            end
            
            app.indices=app.indices-1;            
            app.UITable17.Data=table3;
            
            app.UpdateTableButton_7.BackgroundColor='1.00,0.00,0.00';
        end

        % Button pushed function: UpdateTableButton_7
        function UpdateTableButton_7Pushed(app, event)
            app.UpdateTableButton_7.BackgroundColor='0.96,0.96,0.96';
             l=size(app.UITable17.Data,1);
             query=strcat('DELETE FROM route_link WHERE id_project=',string(app.id_project),'');
             execute(app.connection,query);
             if l>0
                 for i=1:l
                     id_routing=string(app.UITable17.Data{i,1});
                     routing_name=app.UITable17.Data{i,2};
                     id_res=string(app.UITable17.Data{i,4});

                     query=strcat('INSERT INTO route_link (id_routing,routing_name,id_project,id_res) VALUES (', id_routing ,',''' ,routing_name, ''',',string(app.id_project),',',id_res,')');
                     execute(app.connection,query)
                 end
             end
            
            % cancellazione delle rotte nella dalla tabella
            % routing table
            l=size(app.UITable18.Data,1);
            if l>0
                for i=1:l
                     id_routing=string(app.UITable18.Data{i,1});
                     query=strcat('DELETE FROM routing_table WHERE id_routing=',id_routing);
                     execute(app.connection,query)
                 end
            end
            
            app.LoadRoutersButtonPushed();
            app.LoadRoutesButtonPushed();
            
        end

        % Button pushed function: UpdateTableButton_6
        function UpdateTableButton_6Pushed(app, event)
        
            app.UpdateTableButton_6.BackgroundColor='0.96,0.96,0.96';
             l=size(app.UITable15.Data,1);
             query=strcat('DELETE FROM routing_table WHERE id_project=',string(app.id_project),'');
             execute(app.connection,query);
             if l>0
                 for i=1:l
                     id_route=string(app.UITable15.Data{i,1});
                     id_routing=string(app.UITable15.Data{i,2});
                     %id_project
                     name_res=app.UITable15.Data{i,4};
                     res_index=string(app.UITable15.Data{i,6});
                     out_port=string(app.UITable15.Data{i,7});
                     

                     query=strcat('INSERT INTO routing_table (id_route,id_routing,id_project,name_res,res_index,out_port) VALUES (', id_route,',',id_routing,',',string(app.id_project),',''',name_res,''',',res_index,',',out_port,')')
                     execute(app.connection,query)
                 end
             end
        
             app.LoadRoutesButtonPushed();


        end

        % Button pushed function: DeleteSelectedButton_6
        function DeleteSelectedButton_6Pushed(app, event)
            % delete 
            l=height(app.UITable15.Data);
            i=app.indices(1,1);
            
            if isempty(app.UITable16.Data)
                app.UITable16.Data=table(0,0,{''},{''},0,0);
            end
            
            app.route_deleted=app.route_deleted+1;
            app.UITable16.Data(app.route_deleted,1)=app.UITable15.Data(i,1);
            app.UITable16.Data(app.route_deleted,2)=app.UITable15.Data(i,2);
            app.UITable16.Data(app.route_deleted,3)=app.UITable15.Data(i,3);
            app.UITable16.Data(app.route_deleted,4)=app.UITable15.Data(i,4);
            app.UITable16.Data(app.route_deleted,5)=app.UITable15.Data(i,6);
            app.UITable16.Data(app.route_deleted,6)=app.UITable15.Data(i,7);
            
            if i==1
                table3=app.UITable15.Data(2:l,:);
            elseif i==l
                table3=app.UITable15.Data(1:l-1,:);
            else
                table1=app.UITable15.Data(1:i-1,:);
                table2=app.UITable15.Data(i+1:l,:);
                table3=[table1 ; table2];
            end
            
            app.indices=app.indices-1;            
            app.UITable15.Data=table3;
            
            app.UpdateTableButton_6.BackgroundColor='1.00,0.00,0.00';
        
        end

        % Cell selection callback: UITable15
        function UITable15CellSelection(app, event)
            app.indices = event.Indices;
        end

        % Button pushed function: CleanPortsButton
        function CleanPortsButtonPushed(app, event)
            app.CleanPortsButton.BackgroundColor='0.96 0.96 0';
            drawnow
            
            % Pulizia della tabella entity_port dove tutte le porte che
            % corrispondono a entità non esistenti sono cancellate
            query1=strcat('DROP TABLE IF EXISTS entity_port_tmp');
            query2=strcat('CREATE TABLE entity_port_tmp SELECT * FROM entity_port');
            query3=strcat('DELETE FROM entity_port WHERE entity_port.id_project=',string(app.id_project),' AND entity_port.id_port IN (SELECT id_port FROM entity_port_tmp where entity_port_tmp.id_project=',string(app.id_project),' AND entity_port_tmp.id_entity NOT IN (SELECT id_entity FROM entity WHERE entity.id_project=',string(app.id_project),'))');
            execute(app.connection,query1)
            execute(app.connection,query2)
            execute(app.connection,query3)
            
            % Pulizia della tabella entity_port dove tutte le porte che
            % corrispondono a risorse non esistenti sono cancellate
            
            query1=strcat('DROP TABLE IF EXISTS entity_port_tmp');
            query2=strcat('CREATE TABLE entity_port_tmp SELECT * FROM entity_port');
            query3=strcat('DELETE FROM entity_port WHERE entity_port.id_project=',string(app.id_project),' AND entity_port.id_port IN (SELECT id_port FROM entity_port_tmp where entity_port_tmp.id_project=',string(app.id_project),' AND entity_port_tmp.id_res NOT IN (SELECT id_res FROM resources WHERE resources.id_project=',string(app.id_project),'));');
            execute(app.connection,query1)
            execute(app.connection,query2)
            execute(app.connection,query3)
            
            app.CleanPortsButton.BackgroundColor='0.96 0.96 0.96';


        end

        % Button pushed function: LoadLastRunButton
        function LoadLastRunButtonPushed(app, event)
            query=strcat('SELECT COUNT(1) FROM run_output');
            data_count=fetch(app.connection,query);
            app.TotalRecordsEditField.Value=data_count.COUNT_1_;

            if data_count.COUNT_1_>0
                query=strcat('SELECT id_run FROM run ORDER BY id_run DESC');
                data_run=fetch(app.connection,query);
                app.runDropDown.Items=cellstr(num2str(data_run.id_run));
                app.runDropDown.Value=num2str(data_run.id_run(1));
                app.id_run=data_run.id_run(1);
                query=strcat('SELECT sim_time,name_entity,name_var,val_type,val_status FROM run_output WHERE id_run=', string(app.id_run), ' ORDER BY name_entity');
                data_run_output=fetch(app.connection,query);
                app.UITable19.Data=data_run_output;
            else
                app.UITable19.Data=[];
            end


        end

        % Button pushed function: readallentitiesButton_2
        function readallentitiesButton_2Pushed(app, event)
            query=strcat('SELECT DISTINCT name_entity FROM run_output WHERE id_run=''',num2str(app.id_run),''' ORDER BY name_entity');
            data=fetch(app.connection, query);
            
            items=cell(height(data),1);
            for i=1:height(data)
                items{i}=data.name_entity{i};
            end
            
            app.chooseDropDown_2.Items=items;
        end

        % Value changed function: chooseDropDown_2
        function chooseDropDown_2ValueChanged(app, event)
            value = app.chooseDropDown_2.Value;
            query=strcat('SELECT sim_time,name_entity,name_var,val_type,val_status FROM run_output WHERE id_run=', string(app.id_run), ' AND name_entity=''', value ,''' ORDER BY name_entity');
            data_run_output=fetch(app.connection,query);
            app.UITable19.Data=data_run_output;
        end

        % Button pushed function: SelectmodelButton
        function SelectmodelButtonPushed(app, event)
            pause(0.1)
            
            if strcmp(app.EnableModelChangeSwitch.Value,'Off')
                [app.file,app.path] = uigetfile('*.slx');    
            end
            
            figure(app.CISIAproDesignUIFigure)
            
            eval(['addpath '''  app.path ''''])
            app.modelnameEditField.Value=app.file;
            app.modelnameEditField_2.Value=strrep(app.file,'.slx','');
            app.pathEditField.Value=app.path;
            immagine=strrep(app.file,'.slx','.png');
            app.Image6.ImageSource=[app.path 'PNG/' immagine];
            app.Image6.Visible=true;
            app.model=strrep(app.file,'.slx','');
            eval(app.model)
            
            % Initialize switches
            app.singlerunSwitch.Value='On';
            app.EnableFastRestartSwitch.Value='Off';
            app.EnableModelChangeSwitch.Value='Off';
            
            % Initialize flags
            execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''running''')
            execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''run_requested''')
            execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''model_change''');
            execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''reset_model''');
            execute(app.connection,strcat('UPDATE runtime_flags SET value=''', app.modelnameEditField.Value,''' WHERE flag_name=''active_model'''));


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            switch app.singlerunSwitch.Value
                case 'On' % nessun ciclo
                    disp('single run')
                    app.runningLamp.Color=[1 0 0];
                    drawnow
                    start_time=tic;
                    sim(strcat(app.path,app.file));
                    app.runningtimeEditField.Value=toc(start_time);
                    app.runningLamp.Color=[0.5 0 0];
                    
                case 'Off' % ciclo completo che comprende anche il model switching
                    disp('cyclic run')
                    execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''running''')
                    app.cycle_run=1;
                    while app.cycle_run % 
                        app.checkingLamp.Color=[1 1 0];
                        app.modelchangingLamp.Color=[0.1 0.5 0.6];
                        app.runningLamp.Color=[0.5 0 0];
                        app.modelresetLamp.Color=[0 0.3 0.3];
                        drawnow
                        pause(0.1)
                        
                        
                        % RUN_REQUESTED
                        query=('SELECT * FROM runtime_flags WHERE flag_name=''run_requested''');
                        data_flag=fetch(app.connection,query);
                        app.checkingLamp.Color=[0.5 0.5 0];
                        
                        
                        if strcmp(data_flag.value(1),'true')
                           execute(app.connection,'UPDATE runtime_flags SET value=''true'' WHERE flag_name=''running''')
                           execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''run_requested''')
                           app.runningLamp.Color=[1 0 0];
                           app.MessagesEditField.Value='RUNNING';
                           drawnow
                           disp('-> simulation runned')
                           start_time=tic;
                           sim(strcat(app.path,app.file));
                           disp('-> simulazion ended')
                           app.runningtimeEditField.Value=toc(start_time);
                           app.runningLamp.Color=[0.5 0 0]; 
                           app.MessagesEditField.Value='RUN TERMINATED';
                           execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''running''')
                           drawnow
                        end
                        
                        
                        % MODEL_CHANGE
                        query=('SELECT * FROM runtime_flags WHERE flag_name=''model_change''');
                        data_flag=fetch(app.connection,query);
                        app.checkingLamp.Color=[0.5 0.5 0];
                        
                        
                        if strcmp(data_flag.value(1),'true')
                           app.modelchangingLamp.Color=[0.15 0.8 0.9];
                           drawnow
                           query=('SELECT * FROM runtime_flags WHERE flag_name=''active_model''');
                           data_model=fetch(app.connection,query);
                           
                           app.MessagesEditField.Value=strcat('MODEL CHANGING TO: ',data_model.value{1});
                           
                           app.file=strcat(data_model.value{1},'.slx');
                           SelectmodelButtonPushed(app, event);
                           
                        
                           app.TabGroup.SelectedTab=app.SavedStateTab;
                           ShowModelStatusButtonPushed(app, event);
                           CleanStatusButtonPushed(app,event);
                           SetOLButtonPushed(app, event);
                           LoadStatusButtonPushed(app, event);
                           EnableFastRestartSwitchValueChanged(app, event);
                           app.TabGroup.SelectedTab=app.RunManager;
                           
                           execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''model_change''')
                           
                           app.MessagesEditField.Value=strcat('MODEL CHANGED TO: ',data_model.value{1});
                           app.modelchangingLamp.Color=[0.1 0.5 0.6];
                        end
                        
                        % RESET_MODEL
                        query=('SELECT * FROM runtime_flags WHERE flag_name=''reset_model''');
                        data_flag=fetch(app.connection,query);
                        app.checkingLamp.Color=[0.5 0.5 0];
                        
                        
                        if strcmp(data_flag.value(1),'true')
                           app.modelresetLamp.Color=[0 0.9 0.9];
                           drawnow
                           
                           app.MessagesEditField.Value=strcat('MODEL RESET');
                           
                           app.TabGroup.SelectedTab=app.SavedStateTab;
                           ShowModelStatusButtonPushed(app, event);
                           CleanStatusButtonPushed(app,event);
                           SetOLButtonPushed(app, event);
                           LoadStatusButtonPushed(app, event);
                           EnableFastRestartSwitchValueChanged(app, event);
                           app.TabGroup.SelectedTab=app.RunManager;
                           
                           execute(app.connection,'UPDATE runtime_flags SET value=''false'' WHERE flag_name=''reset_model''')
                           
                           app.MessagesEditField.Value=strcat('MODEL RESETTED');
                           app.modelresetLamp.Color=[0 0.3 0.3];
                        end
                        
                        pause(2)
                    
                    end
                    
            end
        end

        % Button pushed function: StoprunningButton
        function StoprunningButtonPushed(app, event)
            app.cycle_run=0;
        end

        % Button pushed function: DuplicateProjectButton
        function DuplicateProjectButtonPushed(app, event)
            
%DROP TABLE IF EXISTS entity_tmp; CREATE TABLE entity_tmp SELECT * FROM entity WHERE id_project=7;
%UPDATE entity_tmp SET id_project=8;
%INSERT INTO entity (id_project, id_type, name_entity)  (SELECT id_project,id_type,name_entity FROM entity_tmp);
%DROP TABLE IF EXISTS entity_tmp;
        
        end

        % Value changed function: runDropDown
        function runDropDownValueChanged(app, event)
            app.id_run = str2num(app.runDropDown.Value);
            query=strcat('SELECT sim_time,name_entity,name_var,val_type,val_status FROM run_output WHERE id_run=', string(app.id_run), ' ORDER BY name_entity');
            data_run_output=fetch(app.connection,query);
            app.UITable19.Data=data_run_output;
            
        end

        % Value changed function: EnableFastRestartSwitch
        function EnableFastRestartSwitchValueChanged(app, event)
            value = app.EnableFastRestartSwitch.Value;
            switch value
                case 'On'
                    set_param(app.model,'FastRestart','on');
                case 'Off'
                    set_param(app.model,'FastRestart','off');
            end            
        end

        % Value changed function: singlerunSwitch
        function singlerunSwitchValueChanged(app, event)
            value = app.singlerunSwitch.Value;

        end

        % Button pushed function: WriteEntitiestoFIleButton
        function WriteEntitiestoFIleButtonPushed(app, event)
            % seleziona tutte le entità del progetto corrente
            
            app.EntityprocessedEditField.Value='Started';
            drawnow
            
            
            query=strcat('SELECT * FROM entity_type WHERE id_project=', string(app.id_project));
            data_id_type=fetch(app.connection,query);
            
            l=height(data_id_type);
            
            for i=1:height(data_id_type)
                
               C(1)=data_id_type.name_type(i);
               C(2)=data_id_type.description(i);
               C(3)=data_id_type.step_received(i);
               C(4)=data_id_type.step_sended(i);
               
               app.EntityprocessedEditField.Value=C{1};
               drawnow
               
               
               query=strcat('select id_entity from entity where id_type=',string(data_id_type.id_type(i)));
               data_id_entity=fetch(app.connection,query);
               
               if height(data_id_entity)>0
                       
                   
                   query=strcat('SELECT * FROM entity_port as a join resources as b on a.id_res=b.id_res where id_entity=',string(data_id_entity.id_entity(1)),' and a.type_port=''INPUT''');
                   data_in_res=fetch(app.connection,query);
                
                   query=strcat('SELECT * FROM entity_port as a join resources as b on a.id_res=b.id_res where id_entity=',string(data_id_entity.id_entity(1)),' and a.type_port=''OUTPUT''');
                   data_out_res=fetch(app.connection,query);
                   
                   query=strcat('SELECT name_var FROM status as a join variables as b on a.id_var=b.id_var where a.id_entity=',string(data_id_entity.id_entity(1)));
                   data_var=fetch(app.connection,query);
                
                   
                   datain=[];
                   if height(data_in_res)>0
                   for j=1:height(data_in_res)
                    datain=[datain newline data_in_res.name_res{j}];
                   end
                   end
                   data2=cell(1,1);
                   data2{1}=datain;
                   
                   dataout=[];
                   if height(data_out_res)>0
                   for j=1:height(data_out_res)
                    dataout=[dataout newline data_out_res.name_res{j}];
                   end
                   end
                   data3=cell(1,1);
                   data3{1}=dataout;
                   
                   datavar=[];
                   if height(data_var)>0
                   for j=1:height(data_var)
                    datavar=[datavar newline data_var.name_var{j}];
                   end
                   end
                   data4=cell(1,1);
                   data4{1}=datavar;


                   writecell(C(1),'EntityType.xlsx','Sheet','Foglio1','Range',strcat('A',string(3+(i-1)*9)));
                   writecell(C(2),'EntityType.xlsx','Sheet','Foglio1','Range',strcat('B',string(3+(i-1)*9)));
                   writecell(C(3),'EntityType.xlsx','Sheet','Foglio1','Range',strcat('A',string(7+(i-1)*9)));
                   writecell(C(4),'EntityType.xlsx','Sheet','Foglio1','Range',strcat('A',string(9+(i-1)*9)));
                   writecell(data2,'EntityType.xlsx','Sheet','Foglio1','Range',strcat('B',string(5+(i-1)*9)));
                   writecell(data3,'EntityType.xlsx','Sheet','Foglio1','Range',strcat('C',string(5+(i-1)*9)));
                   writecell(data4,'EntityType.xlsx','Sheet','Foglio1','Range',strcat('A',string(5+(i-1)*9)));
                
                   
               end
               app.Gauge_2.Value=i/l*100;
               drawnow            

               
            end
            
            app.EntityprocessedEditField.Value='Finished';


        end

        % Button pushed function: LoadInitialStatus
        function LoadInitialStatusButtonPushed(app, event)
            query='SELECT a.id_entity, b.name_entity, a.id_var, c.name_var, a.val_type, a.val_status, a.id_project from status AS a JOIN  entity AS b JOIN variables AS c ON  (a.id_entity=b.id_entity AND a.id_var=c.id_var)  where  a.id_project=6 AND a.val_status<>"" AND c.name_var<>''Operative_Level'' order BY b.name_entity';
            data=fetch(app.connection,query);
            app.UITable3_3.Data=data;
        end

        % Button pushed function: ShowModelStatus
        function ShowModelStatusButtonPushed(app, event)
            query=strcat('SELECT * from status_saved WHERE name_model=''',app.modelnameEditField_2.Value,'''');
            data=fetch(app.connection,query);
            app.UITable3_4.Data=data;
        end

        % Button pushed function: SaveStatus
        function SaveStatusButtonPushed(app, event)
        
            app.SaveStatus.BackgroundColor='1.00,1.00,0.00';
            drawnow
            % clean previously saved status
            query=strcat('DELETE FROM status_saved WHERE name_model=''',app.modelnameEditField_2.Value,'''');
            execute(app.connection,query);
            
            % save status
            
            l=size(app.UITable3_3.Data,1);
            if l>0
                for i=1:l
                    id_entity=string(app.UITable3_3.Data{i,1});
                    name_entity=app.UITable3_3.Data{i,2};
                    id_var=string(app.UITable3_3.Data{i,3});
                    name_var=app.UITable3_3.Data{i,4};
                    val_type=app.UITable3_3.Data{i,5};
                    val_status=app.UITable3_3.Data{i,6};
                    query=strcat('INSERT INTO status_saved (id_entity,name_entity,id_var,name_var,val_type,val_status,id_project,name_model) VALUES (', id_entity ,',''' ,name_entity ,''',', id_var,',''',name_var ,''',''',val_type,''',''',val_status,''' ,' ,string(app.id_project),',''',app.modelnameEditField_2.Value,''')');
                    execute(app.connection,query)
                    app.Gauge.Value=i/l*100;
                    drawnow
                end
                app.Gauge.Value=0;
            end
            
           ShowModelStatusButtonPushed(app, event);    
           app.SaveStatus.BackgroundColor='0.96,0.96,0.96';
           drawnow
           
        end

        % Button pushed function: LoadStatus
        function LoadStatusButtonPushed(app, event)
            app.LoadStatus.BackgroundColor='1.00,1.00,0.00';
            drawnow
            
            l=size(app.UITable3_4.Data,1);
            if l>0
                for i=1:l
                    l=size(app.UITable3_4.Data,1);
                    id_entity=string(app.UITable3_4.Data{i,1});
                    id_var=string(app.UITable3_4.Data{i,2});
                    val_status=string(app.UITable3_4.Data{i,4});
                    
                    query=strcat('UPDATE status SET val_status=''',val_status,''' WHERE id_entity=',id_entity ,' AND id_var=',id_var);
                    execute(app.connection,query)
                    app.Gauge.Value=i/l*100;
                    drawnow
                end
                app.Gauge.Value=0;
            end
            
           LoadInitialStatusButtonPushed(app, event)
           app.LoadStatus.BackgroundColor='0.96,0.96,0.96';
           drawnow
        end

        % Button pushed function: SetOL
        function SetOLButtonPushed(app, event)
            query=strcat('UPDATE status SET val_status=''1'' WHERE id_var=1');
            execute(app.connection,query)
            LoadInitialStatusButtonPushed(app, event)
        end

        % Button pushed function: CleanStatus
        function CleanStatusButtonPushed(app, event)
            query=strcat('UPDATE status SET val_status='''' WHERE id_var<>1');
            execute(app.connection,query)
            LoadInitialStatusButtonPushed(app, event)
        end

        % Button pushed function: DeleteallrunButton
        function DeleteallrunButtonPushed(app, event)
            query=strcat('TRUNCATE run_output');
            execute(app.connection,query)
            query=strcat('TRUNCATE run');
            execute(app.connection,query)
            
            
        end

        % Button pushed function: ReadEntitiesandStateVariablesButton
        function ReadEntitiesandStateVariablesButtonPushed(app, event)
            app.LoadLastRunButtonPushed(app)
            
            query=strcat('SELECT DISTINCT name_var FROM run_output WHERE id_run=', string(app.id_run), ' ORDER BY name_var');
            data_run_output=fetch(app.connection,query);
            
            items=cell(height(data_run_output),1);
            for i=1:height(data_run_output)
                items{i}=data_run_output.name_var{i};
            end
            
            app.StateVariablesDropDown.Items=items;


        end

        % Button pushed function: RepaintEntitiesButton
        function RepaintEntitiesButtonPushed(app, event)
            
            cisiacolormap=hsv(60);
            
            query=strcat('SELECT name_entity, block_handler FROM actual_entities');
            data_entities=fetch(app.connection,query);
            
            num_ent=height(data_entities);
            if num_ent>0
                for i=1:num_ent
                    % get the value of the variable
                    query=strcat('SELECT val_status FROM run_output WHERE id_run=', string(app.id_run),' AND name_entity=''',data_entities.name_entity(i),''' AND name_var=''',app.StateVariablesDropDown.Value,'''');
                    data_val_status=fetch(app.connection,query);
                                       
                    if height(data_val_status)>0
                        op_level=eval(data_val_status.val_status{1});
                        index=floor(20*op_level)+1;


                        if app.ReverseCheckBox.Value==0
                            color=num2str(cisiacolormap(index,:));
                        else
                            color=num2str(cisiacolormap(22-index,:));
                            
                        end
                        
                    else
                        color='1 1 1';
                    end
                    
                    %disp(data_entities.block_handler{i})
                              
                    set_param(data_entities.block_handler{i},'BackgroundColor',['[' color ']'])


                end
            end
            
        end

        % Button pushed function: AddarowButton_5
        function AddarowButton_5Pushed(app, event)
            
            
            query=strcat('SELECT * from resources WHERE id_project=',string(app.id_project),' ORDER BY name_res ASC');
            data=fetch(app.connection,query);
            
            f = listdlg('PromptString','For which resource?','SelectionMode','single','ListString',data.name_res);
         
            figure(app.CISIAproDesignUIFigure)
            
            if f>0


                app.num_save2db=app.num_save2db+1;
                 
                 if app.last_save2db==0
                     new_save2db=app.new_save2db();
                     app.last_save2db=new_save2db;
                 else
                     app.last_save2db=app.last_save2db+1;
                     new_save2db=app.last_save2db;
                 end
                 
                 
                if app.num_save2db==1
                    app.UITable13.Data=table(new_save2db,{'<insert name>'},data.name_res(f));
                else
                    app.UITable13.Data{app.num_save2db,1}=new_save2db;
                    app.UITable13.Data{app.num_save2db,2}={'<insert name>'};
                    app.UITable13.Data{app.num_save2db,3}=data.name_res(f);
                    app.UITable13.Data{app.num_save2db,4}=data.id_res(f);
                    
                end
    
                 app.UpdateTableButton_5.BackgroundColor='1.00,0.00,0.00';
            else
                disp('Nessuna risorsa selezionata')
            
            end


        end

        % Button pushed function: DeleteSelectedButton_5
        function DeleteSelectedButton_5Pushed(app, event)
            
            % delete 
            l=height(app.UITable13.Data);
            i=app.indices(1,1);
            
            if isempty(app.UITable14.Data)
                app.UITable14.Data=table(0,{''},{''},0);
            end
            
            app.save2db_deleted=app.save2db_deleted+1;
            app.UITable14.Data(app.save2db_deleted,1)=app.UITable13.Data(i,1);
            app.UITable14.Data(app.save2db_deleted,2)=app.UITable13.Data(i,2);
            app.UITable14.Data(app.save2db_deleted,3)=app.UITable13.Data(i,3);
            app.UITable14.Data(app.save2db_deleted,4)=app.UITable13.Data(i,4);
             
             if i==1
                 table3=app.UITable13.Data(2:l,:);
             elseif i==l
                 table3=app.UITable13.Data(1:l-1,:);
             else
                 table1=app.UITable13.Data(1:i-1,:);
                 table2=app.UITable13.Data(i+1:l,:);
                 table3=[table1 ; table2];
             end

             app.indices=app.indices-1;            
             app.UITable13.Data=table3;
             
             app.UpdateTableButton_5.BackgroundColor='1.00,0.00,0.00';


        end

        % Button pushed function: UpdateTableButton_5
        function UpdateTableButton_5Pushed(app, event)
            app.UpdateTableButton_5.BackgroundColor='0.96,0.96,0.96';
             l=size(app.UITable13.Data,1);
             query=strcat('DELETE FROM save2db WHERE id_project=',string(app.id_project),'');
             execute(app.connection,query);
             if l>0
                 for i=1:l
                     id_save2db=string(app.UITable13.Data{i,1});
                     save_name=app.UITable13.Data{i,2};
                     id_res=string(app.UITable13.Data{i,4});

                     query=strcat('INSERT INTO save2db (id_save2db,save_name,id_res,id_project) VALUES (', id_save2db ,',''' ,save_name, ''',',id_res,',',string(app.id_project),')');
                     execute(app.connection,query)
                 end
             end
           
            app.LoadSave2DBButtonPushed()


        end

        % Cell selection callback: UITable13
        function UITable13CellSelection(app, event)
            app.indices = event.Indices;
            
            
        end

        % Cell selection callback: UITable14
        function UITable14CellSelection(app, event)
            app.indices = event.Indices;
            
        end

        % Cell edit callback: UITable13
        function UITable13CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: ShowVariablesButton
        function ShowVariablesButtonPushed(app, event)
            
            query=strcat('SELECT id_var,name_var,val_type,var_dim FROM variables WHERE id_project=',string(app.id_project),' ORDER BY name_var ');
            data_variables=fetch(app.connection, query);
            app.num_entity_variables=height(data_variables);
            app.UITable20.Data=[];
            if app.num_entity_variables>0
                app.UITable20.Data=data_variables;
            end
            s = uistyle('BackgroundColor','white');
            app.UITable20.removeStyle;
            
            app.selected_variables=zeros(app.num_entity_variables,1);


        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            
        end

        % Cell selection callback: UITable20
        function UITable20CellSelection(app, event)
            indices = event.Indices;
            
            app.selected_variables(indices(1),1)=1-app.selected_variables(indices(1),1);
            
            if app.selected_variables(indices(1),1)==1
                s = uistyle('BackgroundColor','yellow');
            else
                s = uistyle('BackgroundColor','white');
            end
                
            addStyle(app.UITable20,s,'cell',[indices(1),indices(2)]);
            
            
        end

        % Button pushed function: ReadAllModels
        function ReadAllModelsButtonPushed(app, event)
            query=strcat('SELECT DISTINCT name_model FROM status_saved WHERE id_project=''',num2str(app.id_project),''' ORDER BY name_model');
            data=fetch(app.connection, query);
            
            items=cell(height(data),1);
            for i=1:height(data)
                items{i}=data.name_model{i};
            end
            
            app.modelsDropDown.Items=items;


        end

        % Value changed function: modelsDropDown
        function modelsDropDownValueChanged(app, event)
            value = app.modelsDropDown.Value;
            app.modelnameEditField_2.Value=value;
            app.ShowModelStatusButtonPushed
        end

        % Button pushed function: AddSelectedButton
        function AddSelectedButtonPushed(app, event)
            
            
              % recupero id_var
            for j=1:app.num_entity_variables  
              if app.selected_variables(j,1)==1
                a=app.UITable20.Data{j,2};
                
                    if ~isempty(app.UITable10.Data)
                        rowIdx = strcmp(app.UITable10.Data{:,1}, a);
                    else
                        rowIdx=[];
                    end
                    if not(isempty(find(rowIdx)))
                        disp(['variable ' a{1} ' already included'])
                    
                    else
                   
                        query=strcat('SELECT * FROM variables WHERE name_var=''',a{1},'''');
                        data=fetch(app.connection,query);
                        id_var=data.id_var(1);
                        val_type=data.val_type(1);
                        
                    
                        % per tutte le entità di tipo id_entity va aggiunta la variabile
                        % id_var
                        
                        query=strcat('SELECT * FROM entity WHERE id_type=''',string(app.id_type),''' AND id_project=',string(app.id_project));
                        data=fetch(app.connection,query);
                        l=height(data);
                        
                        for i=1:l
                            query=strcat('INSERT INTO status (id_entity, id_var, val_type, val_status, id_project) VALUES (',string(data.id_entity(i)),',',string(id_var),',''',val_type,''', '''', ',string(app.id_project),')');
                            execute(app.connection,query)
                        end
                    end
                
                
              end
              
            end
                    
            a=app.read_state_variables();
            
            s = uistyle('BackgroundColor','white');
            app.UITable20.removeStyle;
            
            app.selected_variables=zeros(app.num_entity_variables,1);


        end

        % Button pushed function: AutoDiscoverButton
        function AutoDiscoverButtonPushed(app, event)
            
            app.ShowVariablesButtonPushed();
            num_var=height(app.UITable20.Data);
            text=[app.step_receivedTextArea.Value{:} app.step_computedTextArea.Value{:} app.dynamic_computedTextArea.Value{:} app.step_sendedTextArea.Value{:}]
                
            for i=1:num_var
                name_var=app.UITable20.Data{i,2};
                k = strfind(text,name_var{1});
                if not(isempty(k))
                   %disp(['found ' name_var{1} ' in step_received'])
                   s = uistyle('BackgroundColor','yellow');
                   addStyle(app.UITable20,s,'cell',[i,2]);
                   app.selected_variables(i,1)=1;
            
                end            
                
                
            end


        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            % nome del modello:     app.modelnameEditField_2.Value
            
            sz = [0 5];
            
            varTypes = ["string","double","string","double","string","string"];
            varNames = ["SrcBlock","SrcPort","DestBlock","DestPort","Resource","BlockType"];
            app.Links = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);


        end

        % Button pushed function: RenameEntityButton_2
        function RenameEntityButton_2Pushed(app, event)
            
            % verifica che il nome non esista già
            query=strcat('SELECT * FROM entity WHERE name_entity=''',app.nameEditField.Value,''' AND id_project=',string(app.id_project));
            data=fetch(app.connection,query);
            if isempty(data)

                query=strcat('UPDATE entity SET name_entity=''',app.nameEditField.Value,''' WHERE id_entity=',string(app.id_entity),' AND id_project=',string(app.id_project));
                execute(app.connection,query);
                app.readallentitiesButtonPushed;
                app.chooseDropDown.Value =app.nameEditField.Value;


            else
                errordlg('Name already exist!');
            end
        end

        % Callback function
        function DeleteEntityButton_2Pushed(app, event)
            query=strcat('DELETE FROM entity WHERE name_entity=''',app.nameEditField.Value,''' WHERE id_entity=',string(app.id_entity),' AND id_project=',string(app.id_project));
            %execute(app.connection,query);
        end

        % Button pushed function: RenameEntityTypeButton
        function RenameEntityTypeButtonPushed(app, event)
            

            % verifica che il nome non esista già
            query=strcat('SELECT * FROM entity_type WHERE name_type=''',app.nameEditField.Value,''' AND id_project=',string(app.id_project));
            data=fetch(app.connection,query);
            if isempty(data)

                query=strcat('UPDATE entity_type SET name_type=''',app.nameEditField.Value,''' WHERE name_type=''',string(app.typeDropDown.Value),''' AND id_project=',string(app.id_project));
                execute(app.connection,query);
                %app.readallentitiesButtonPushed;
                %app.readall
                %app.chooseDropDown.Value =app.nameEditField.Value;
                app.read_all_entity_type;

            else
                errordlg('Name already exist!');
            end
       

        end

        % Button pushed function: DeleteEntityTypeButton
        function DeleteEntityTypeButtonPushed(app, event)
            % verifica che il nome esista in maniera univoca
            query=strcat('SELECT * FROM entity_type WHERE name_type=''',string(app.typeDropDown.Value),''' AND id_project=',string(app.id_project));
            data=fetch(app.connection,query);
            if height(data)==1

                answer = questdlg(strcat('Are you sure you want to delete entity type:',data.name_type,' and all entities associated?'), 'Confirm','Yes','No','No');
                
                switch answer
                    case 'Yes'

                        id_entity_type=data.id_type;

                        % cancella l'entity type
                        query=strcat('DELETE FROM entity_type WHERE id_type=',string(id_entity_type),' AND id_project=',string(app.id_project));
                        execute(app.connection,query)
                        
                        % determina tutte le entity di quel tipo
                        query=strcat('SELECT * FROM entity WHERE id_type=',num2str(id_entity_type), ' AND id_project=',string(app.id_project));
                        data_entities=fetch(app.connection, query);

                        if height(data_entities)>0
                            % cancella tutte le porte e tutte le variabili di stato associate alle precedenti
                            % entità 
                            for i=1:height(data_entities)
                                query=strcat('DELETE FROM entity_port WHERE id_entity=',string(data_entities.id_entity(i)),' AND id_project=',string(app.id_project));
                                execute(app.connection,query)
                                
                                query=strcat('DELETE FROM status WHERE id_entity=',string(data_entities.id_entity(i)),' AND id_project=',string(app.id_project));
                                execute(app.connection,query)

                            end


                            % cancella tutte le entità
                            query=strcat('DELETE FROM entity WHERE id_type=',string(id_entity_type),' AND id_project=',string(app.id_project));
                            execute(app.connection,query)

                        end
                        
                        query3=strcat('SELECT * FROM entity WHERE id_project=',string(app.id_project));
                        data3=fetch(app.connection, query3);
                        app.chooseDropDown.Items=data3.name_entity;

                        if height(data3)>0
                            app.chooseDropDown.Value=data3.name_entity(1);
                            app.chooseDropDown.ValueChangedFcn(app,event);
                        else
                            app.chooseDropDown.Value='';
                        end
                
                        
    %                     a=app.read_state_variables();
    %                     a=app.read_inputs();
    %                     a=app.read_outputs();
                        
                        % ricarica tutte le entità nelle tendine
                        app.read_all_entity_type;


                    case 'No'
                end

            else
                errordlg('Error: More Entities Types with the same name');
            end


        end

        % Value changing function: filterentitynameEditField
        function filterentitynameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            

            % Ricerca nella tabella entity
            
            query3=strcat('SELECT * FROM entity WHERE name_entity LIKE ''%', changingValue, '%'' AND id_project=',string(app.id_project));
            data3=fetch(app.connection, query3);
            if height(data3)>0
                app.chooseDropDown.Items=data3.name_entity;
                if height(data3)>0
                    app.chooseDropDown.ValueChangedFcn(app,event);
                end
            end
            
            app.colorEditFieldValueChanged();


        end

        % Button pushed function: ExportProjectButton
        function ExportProjectButtonPushed(app, event)
            % export entities, entity_type, entity_port, resources,
            % route_link, routing_table, save2db, status, variables

            % nel caso in cui il flag only active entities sia attivo
            % entities: solo quelle attive
            % entity_type: sono quelle delle entities attive
            % entity_port: solo quelle delle entity attive
            % resources: solo le risorse rimaste tra le entity_port
            % route_link: solo quelle attive
            % routing_table: solo quelle con id_routing attivo
            % save2db: solo quelle attive
            % status: solo quello con id_entity attivo
            % variables: solo quelle rimaste in status


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CISIAproDesignUIFigure = uifigure('Visible', 'off');
            colormap(app.CISIAproDesignUIFigure, 'parula');
            app.CISIAproDesignUIFigure.Name = 'CISIAproDesign';
            app.Image2 = uiimage(app.CISIAproDesignUIFigure);
            app.Image2.ImageSource = 'logo_on_black.png';
            app.TabGroup = uitabgroup(app.CISIAproDesignUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.Image3 = uiimage(app.DatabaseTab);
            app.Image3.ImageSource = 'CISIApro_v4.png';
            app.DatabasePanel = uipanel(app.DatabaseTab);
            app.DatabasePanel.Title = 'Database';
            app.ConnectButton = uibutton(app.DatabasePanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed2, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectiondataTextArea = uitextarea(app.DatabasePanel);
            app.MessageDBLabel = uilabel(app.DatabasePanel);
            app.MessageDBLabel.Text = 'Message/DB';
            app.PasswordEditField_2Label = uilabel(app.DatabasePanel);
            app.PasswordEditField_2Label.Text = 'Password';
            app.PasswordEditField = uieditfield(app.DatabasePanel, 'text');
            app.choosecatalogDropDownLabel = uilabel(app.DatabasePanel);
            app.choosecatalogDropDownLabel.Text = 'choose catalog';
            app.choosecatalogDropDown = uidropdown(app.DatabasePanel);
            app.choosecatalogDropDown.Items = {};
            app.choosecatalogDropDown.ValueChangedFcn = createCallbackFcn(app, @choosecatalogDropDownValueChanged, true);
            app.choosecatalogDropDown.Value = {};
            app.urlDropDownLabel = uilabel(app.DatabasePanel);
            app.urlDropDownLabel.Text = 'url';
            app.urlDropDown = uidropdown(app.DatabasePanel);
            app.urlDropDown.Items = {};
            app.urlDropDown.Value = {};
            app.UserNameDropDownLabel = uilabel(app.DatabasePanel);
            app.UserNameDropDownLabel.Text = 'User Name';
            app.UserNameDropDown = uidropdown(app.DatabasePanel);
            app.UserNameDropDown.Items = {};
            app.UserNameDropDown.Value = {};
            app.JavaConnectorEditFieldLabel = uilabel(app.DatabasePanel);
            app.JavaConnectorEditFieldLabel.Text = 'Java Connector';
            app.JavaConnectorEditField = uieditfield(app.DatabasePanel, 'text');
            app.JavaConnectorEditField.Value = 'mysql-connector-j-8.0.33.jar';
            app.ProjectPanel = uipanel(app.DatabaseTab);
            app.ProjectPanel.Title = 'Project';
            app.ReadProjectsButton_2 = uibutton(app.ProjectPanel, 'push');
            app.ReadProjectsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReadProjectsButton_2Pushed, true);
            app.ReadProjectsButton_2.Text = 'Read Projects';
            app.ChooseProjectDropDown_2Label = uilabel(app.ProjectPanel);
            app.ChooseProjectDropDown_2Label.Text = 'Choose Project';
            app.ChooseProjectDropDown_2 = uidropdown(app.ProjectPanel);
            app.ChooseProjectDropDown_2.Items = {};
            app.ChooseProjectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChooseProjectDropDown_2ValueChanged, true);
            app.ChooseProjectDropDown_2.Value = {};
            app.DuplicateProjectButton = uibutton(app.ProjectPanel, 'push');
            app.DuplicateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateProjectButtonPushed, true);
            app.DuplicateProjectButton.Enable = 'off';
            app.DuplicateProjectButton.Text = 'Duplicate Project';
            app.NewEmptyProjectButton = uibutton(app.ProjectPanel, 'push');
            app.NewEmptyProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewEmptyProjectButtonPushed, true);
            app.NewEmptyProjectButton.Text = 'New Empty Project';
            app.nameEditField_3Label = uilabel(app.ProjectPanel);
            app.nameEditField_3Label.Text = 'name';
            app.nameEditField_3 = uieditfield(app.ProjectPanel, 'text');
            app.RenameProjectButton = uibutton(app.ProjectPanel, 'push');
            app.RenameProjectButton.Enable = 'off';
            app.RenameProjectButton.Text = 'Rename Project';
            app.id_projectEditFieldLabel = uilabel(app.ProjectPanel);
            app.id_projectEditFieldLabel.Text = 'id_project';
            app.id_projectEditField = uieditfield(app.ProjectPanel, 'numeric');
            app.id_projectEditField.Editable = 'off';
            app.ExportProjectButton = uibutton(app.ProjectPanel, 'push');
            app.ExportProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ExportProjectButtonPushed, true);
            app.ExportProjectButton.Text = 'Export Project';
            app.OnlyActiveEntitiesCheckBox = uicheckbox(app.ProjectPanel);
            app.OnlyActiveEntitiesCheckBox.Text = 'Only Active Entities';
            app.ImportProjectButton = uibutton(app.ProjectPanel, 'push');
            app.ImportProjectButton.Enable = 'off';
            app.ImportProjectButton.Text = 'Import Project';
            app.Image5 = uiimage(app.DatabaseTab);
            app.Image5.ImageSource = 'matlab3.PNG';
            app.EntityMakerTab = uitab(app.TabGroup);
            app.EntityMakerTab.Title = 'Entity Maker';
            app.Panel = uipanel(app.EntityMakerTab);
            app.Panel.Title = 'Panel';
            app.readallentitiesButton = uibutton(app.Panel, 'push');
            app.readallentitiesButton.ButtonPushedFcn = createCallbackFcn(app, @readallentitiesButtonPushed, true);
            app.readallentitiesButton.Text = 'read all entities';
            app.chooseDropDownLabel = uilabel(app.Panel);
            app.chooseDropDownLabel.Text = 'choose';
            app.chooseDropDown = uidropdown(app.Panel);
            app.chooseDropDown.Items = {};
            app.chooseDropDown.ValueChangedFcn = createCallbackFcn(app, @chooseDropDownValueChanged, true);
            app.chooseDropDown.Value = {};
            app.id_entityEditFieldLabel = uilabel(app.Panel);
            app.id_entityEditFieldLabel.Text = 'id_entity';
            app.id_entityEditField = uieditfield(app.Panel, 'numeric');
            app.id_entityEditField.Editable = 'off';
            app.RemoveInputButton = uibutton(app.Panel, 'push');
            app.RemoveInputButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveInputButtonPushed, true);
            app.RemoveInputButton.Text = 'Remove Input';
            app.AddInputButton = uibutton(app.Panel, 'push');
            app.AddInputButton.ButtonPushedFcn = createCallbackFcn(app, @AddInputButtonPushed, true);
            app.AddInputButton.Text = 'Add Input';
            app.chooseinputDropDownLabel = uilabel(app.Panel);
            app.chooseinputDropDownLabel.Text = 'choose input';
            app.chooseinputDropDown = uidropdown(app.Panel);
            app.chooseinputDropDown.Items = {};
            app.chooseinputDropDown.ValueChangedFcn = createCallbackFcn(app, @chooseinputDropDownValueChanged, true);
            app.chooseinputDropDown.Value = {};
            app.RemoveOutputButton = uibutton(app.Panel, 'push');
            app.RemoveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOutputButtonPushed, true);
            app.RemoveOutputButton.Text = 'Remove Output';
            app.AddOutputButton = uibutton(app.Panel, 'push');
            app.AddOutputButton.ButtonPushedFcn = createCallbackFcn(app, @AddOutputButtonPushed, true);
            app.AddOutputButton.Text = 'Add Output';
            app.chooseoutputDropDownLabel = uilabel(app.Panel);
            app.chooseoutputDropDownLabel.Text = 'choose output';
            app.chooseoutputDropDown = uidropdown(app.Panel);
            app.chooseoutputDropDown.Items = {};
            app.chooseoutputDropDown.ValueChangedFcn = createCallbackFcn(app, @chooseoutputDropDownValueChanged, true);
            app.chooseoutputDropDown.Value = {};
            app.RemoveVarButton = uibutton(app.Panel, 'push');
            app.RemoveVarButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveVarButtonPushed, true);
            app.RemoveVarButton.Text = 'Remove Var';
            app.AddVariableButton = uibutton(app.Panel, 'push');
            app.AddVariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddVariableButtonPushed, true);
            app.AddVariableButton.Text = 'Add Variable';
            app.choosevariableDropDownLabel = uilabel(app.Panel);
            app.choosevariableDropDownLabel.Text = 'choose variable';
            app.choosevariableDropDown = uidropdown(app.Panel);
            app.choosevariableDropDown.Items = {};
            app.choosevariableDropDown.Value = {};
            app.UITable8 = uitable(app.Panel);
            app.UITable8.ColumnName = {'Input Resources'; 'dim'};
            app.UITable8.RowName = {};
            app.UITable8.CellSelectionCallback = createCallbackFcn(app, @UITable8CellSelection, true);
            app.UITable9 = uitable(app.Panel);
            app.UITable9.ColumnName = {'Output Resources'; 'dim'};
            app.UITable9.RowName = {};
            app.UITable9.CellSelectionCallback = createCallbackFcn(app, @UITable9CellSelection, true);
            app.UpdatestatusButton = uibutton(app.Panel, 'push');
            app.UpdatestatusButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatestatusButtonPushed, true);
            app.UpdatestatusButton.Text = 'Update status';
            app.filterentitynameEditFieldLabel = uilabel(app.Panel);
            app.filterentitynameEditFieldLabel.Text = 'filter entity name';
            app.filterentitynameEditField = uieditfield(app.Panel, 'text');
            app.filterentitynameEditField.ValueChangingFcn = createCallbackFcn(app, @filterentitynameEditFieldValueChanging, true);
            app.Panel2 = uipanel(app.EntityMakerTab);
            app.Panel2.Title = 'Panel2';
            app.TabGroup2 = uitabgroup(app.Panel2);
            app.step_receivedTab = uitab(app.TabGroup2);
            app.step_receivedTab.Title = 'step_received';
            app.step_receivedTextArea = uitextarea(app.step_receivedTab);
            app.step_computedTab = uitab(app.TabGroup2);
            app.step_computedTab.Title = 'step_computed';
            app.step_computedTextArea = uitextarea(app.step_computedTab);
            app.dynamic_computedTab = uitab(app.TabGroup2);
            app.dynamic_computedTab.Title = 'dynamic_computed';
            app.dynamic_computedTextArea = uitextarea(app.dynamic_computedTab);
            app.step_sendedTab = uitab(app.TabGroup2);
            app.step_sendedTab.Title = 'step_sended';
            app.step_sendedTextArea = uitextarea(app.step_sendedTab);
            app.updateEntityTypebehaviorButton = uibutton(app.Panel2, 'push');
            app.updateEntityTypebehaviorButton.ButtonPushedFcn = createCallbackFcn(app, @updateEntityTypebehaviorButtonPushed, true);
            app.updateEntityTypebehaviorButton.Text = 'update Entity Type behavior';
            app.fourstepsdescriptionentitytypenamecolorLabel = uilabel(app.Panel2);
            app.fourstepsdescriptionentitytypenamecolorLabel.Text = '(four steps+description+entity type name+color)';
            app.descriptionTextAreaLabel = uilabel(app.Panel2);
            app.descriptionTextAreaLabel.Text = 'description';
            app.descriptionTextArea = uitextarea(app.Panel2);
            app.entitytypeTextAreaLabel = uilabel(app.Panel2);
            app.entitytypeTextAreaLabel.Text = 'entity type';
            app.entitytypeTextArea = uitextarea(app.Panel2);
            app.colorEditFieldLabel = uilabel(app.Panel2);
            app.colorEditFieldLabel.Text = 'color';
            app.colorEditField = uieditfield(app.Panel2, 'text');
            app.colorEditField.ValueChangedFcn = createCallbackFcn(app, @colorEditFieldValueChanged, true);
            app.ColorpickerButton = uibutton(app.Panel2, 'push');
            app.ColorpickerButton.ButtonPushedFcn = createCallbackFcn(app, @ColorpickerButtonPushed, true);
            app.ColorpickerButton.Text = 'Color picker';
            app.id_typeEditFieldLabel = uilabel(app.Panel2);
            app.id_typeEditFieldLabel.Text = 'id_type';
            app.id_typeEditField = uieditfield(app.Panel2, 'text');
            app.id_typeEditField.Editable = 'off';
            app.EntityTypeDropDownLabel = uilabel(app.Panel2);
            app.EntityTypeDropDownLabel.Text = 'Entity Type';
            app.EntityTypeDropDown = uidropdown(app.Panel2);
            app.EntityTypeDropDown.Items = {'', ''};
            app.EntityTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EntityTypeDropDownValueChanged, true);
            app.EntityTypeDropDown.Value = '';
            app.ActualProjectEditFieldLabel = uilabel(app.Panel2);
            app.ActualProjectEditFieldLabel.Text = 'Actual Project';
            app.ActualProjectEditField = uieditfield(app.Panel2, 'text');
            app.ActualProjectEditField.ValueChangedFcn = createCallbackFcn(app, @ActualProjectEditFieldValueChanged, true);
            app.ActualProjectEditField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.EntityMakerTab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.StatevariablesTab = uitab(app.TabGroup3);
            app.StatevariablesTab.Title = 'State variables';
            app.UITable10 = uitable(app.StatevariablesTab);
            app.UITable10.ColumnName = {'State Variables'; 'type'; 'dimension'; 'status'};
            app.UITable10.RowName = {};
            app.UITable10.ColumnEditable = [false false false true];
            app.UITable10.CellSelectionCallback = createCallbackFcn(app, @UITable10CellSelection, true);
            app.UITable10.DisplayDataChangedFcn = createCallbackFcn(app, @UITable10DisplayDataChanged, true);
            app.MultipleAddTab = uitab(app.TabGroup3);
            app.MultipleAddTab.Title = 'Multiple Add';
            app.UITable20 = uitable(app.MultipleAddTab);
            app.UITable20.ColumnName = {'id_var'; 'name_var'; 'val_type'; 'var_dim'};
            app.UITable20.RowName = {};
            app.UITable20.CellSelectionCallback = createCallbackFcn(app, @UITable20CellSelection, true);
            app.AddSelectedButton = uibutton(app.MultipleAddTab, 'push');
            app.AddSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectedButtonPushed, true);
            app.AddSelectedButton.Text = 'Add Selected';
            app.ShowVariablesButton = uibutton(app.MultipleAddTab, 'push');
            app.ShowVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVariablesButtonPushed, true);
            app.ShowVariablesButton.Text = 'Show Variables';
            app.AutoDiscoverButton = uibutton(app.MultipleAddTab, 'push');
            app.AutoDiscoverButton.ButtonPushedFcn = createCallbackFcn(app, @AutoDiscoverButtonPushed, true);
            app.AutoDiscoverButton.Text = 'Auto Discover';
            app.TabGroup4 = uitabgroup(app.EntityMakerTab);
            app.CreateDeleteTab = uitab(app.TabGroup4);
            app.CreateDeleteTab.Title = 'Create/Delete';
            app.nameEditFieldLabel = uilabel(app.CreateDeleteTab);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.CreateDeleteTab, 'text');
            app.typeDropDownLabel = uilabel(app.CreateDeleteTab);
            app.typeDropDownLabel.Text = 'type:';
            app.typeDropDown = uidropdown(app.CreateDeleteTab);
            app.typeDropDown.Items = {};
            app.typeDropDown.Value = {};
            app.newemptyEntityTypeButton = uibutton(app.CreateDeleteTab, 'push');
            app.newemptyEntityTypeButton.ButtonPushedFcn = createCallbackFcn(app, @newemptyEntityTypeButtonPushed, true);
            app.newemptyEntityTypeButton.Text = 'new empty Entity Type';
            app.newEntityofEntityTypeButton = uibutton(app.CreateDeleteTab, 'push');
            app.newEntityofEntityTypeButton.ButtonPushedFcn = createCallbackFcn(app, @newEntityofEntityTypeButtonPushed, true);
            app.newEntityofEntityTypeButton.Text = 'new Entity of Entity Type';
            app.RenameEntityButton_2 = uibutton(app.CreateDeleteTab, 'push');
            app.RenameEntityButton_2.ButtonPushedFcn = createCallbackFcn(app, @RenameEntityButton_2Pushed, true);
            app.RenameEntityButton_2.Text = 'Rename Entity';
            app.DeleteEntityTypeButton = uibutton(app.CreateDeleteTab, 'push');
            app.DeleteEntityTypeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEntityTypeButtonPushed, true);
            app.DeleteEntityTypeButton.Text = 'Delete Entity Type';
            app.DeleteEntityButton = uibutton(app.CreateDeleteTab, 'push');
            app.DeleteEntityButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEntityButtonPushed, true);
            app.DeleteEntityButton.Text = 'Delete Entity';
            app.RenameEntityTypeButton = uibutton(app.CreateDeleteTab, 'push');
            app.RenameEntityTypeButton.ButtonPushedFcn = createCallbackFcn(app, @RenameEntityTypeButtonPushed, true);
            app.RenameEntityTypeButton.Text = 'Rename Entity Type';
            app.ExportInportTab = uitab(app.TabGroup4);
            app.ExportInportTab.Title = 'Export/Inport';
            app.insidetheprojectDropDownLabel = uilabel(app.ExportInportTab);
            app.insidetheprojectDropDownLabel.Text = 'inside the project';
            app.insidetheprojectDropDown = uidropdown(app.ExportInportTab);
            app.insidetheprojectDropDown.Items = {};
            app.insidetheprojectDropDown.Value = {};
            app.ExportEntityButton = uibutton(app.ExportInportTab, 'push');
            app.ExportEntityButton.Enable = 'off';
            app.ExportEntityButton.Text = 'Export Entity';
            app.duplicateEntityTypeButton = uibutton(app.ExportInportTab, 'push');
            app.duplicateEntityTypeButton.ButtonPushedFcn = createCallbackFcn(app, @duplicateEntityTypeButtonPushed, true);
            app.duplicateEntityTypeButton.Text = 'duplicate Entity Type';
            app.InportEntityButton = uibutton(app.ExportInportTab, 'push');
            app.InportEntityButton.Enable = 'off';
            app.InportEntityButton.Text = 'Inport Entity';
            app.StateVariablesTab = uitab(app.TabGroup);
            app.StateVariablesTab.Title = 'State Variables';
            app.UITable3 = uitable(app.StateVariablesTab);
            app.UITable3.ColumnName = {'id_var'; 'name_var'; 'val_type'; 'var_dim'; 'id_project'; 'description'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = [false true true true false true];
            app.UITable3.CellSelectionCallback = createCallbackFcn(app, @UITable3CellSelection, true);
            app.LoadVariablesButton = uibutton(app.StateVariablesTab, 'push');
            app.LoadVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVariablesButtonPushed, true);
            app.LoadVariablesButton.Text = 'Load Variables';
            app.AddarowButton_2 = uibutton(app.StateVariablesTab, 'push');
            app.AddarowButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddarowButton_2Pushed, true);
            app.AddarowButton_2.Text = 'Add a row';
            app.DeleteSelectedButton_2 = uibutton(app.StateVariablesTab, 'push');
            app.DeleteSelectedButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButton_2Pushed, true);
            app.DeleteSelectedButton_2.Text = 'Delete Selected';
            app.UpdateTableButton = uibutton(app.StateVariablesTab, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.ProjectEditField_2Label = uilabel(app.StateVariablesTab);
            app.ProjectEditField_2Label.Text = 'Project';
            app.ProjectEditField_2 = uieditfield(app.StateVariablesTab, 'text');
            app.ProjectEditField_2.Editable = 'off';
            app.UITable5 = uitable(app.StateVariablesTab);
            app.UITable5.ColumnName = {'id_var'; 'name_var'; 'id_project'};
            app.UITable5.RowName = {};
            app.UITable5.ColumnEditable = [false false false];
            app.variablestobedeletedLabel = uilabel(app.StateVariablesTab);
            app.variablestobedeletedLabel.Text = 'variables to be deleted';
            app.UITable3_2 = uitable(app.StateVariablesTab);
            app.UITable3_2.ColumnName = {'entity_name'; 'entity_type'};
            app.UITable3_2.RowName = {};
            app.UITable3_2.ColumnEditable = [false false];
            app.entitieswithselectedvariableLabel = uilabel(app.StateVariablesTab);
            app.entitieswithselectedvariableLabel.Text = 'entities with selected variable';
            app.LayersResourcesTab = uitab(app.TabGroup);
            app.LayersResourcesTab.Title = 'Layers & Resources';
            app.ProjectEditFieldLabel = uilabel(app.LayersResourcesTab);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.LayersResourcesTab, 'text');
            app.ProjectEditField.Editable = 'off';
            app.LoadResourcesButton = uibutton(app.LayersResourcesTab, 'push');
            app.LoadResourcesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResourcesButtonPushed, true);
            app.LoadResourcesButton.Text = 'Load Resources';
            app.UITable2 = uitable(app.LayersResourcesTab);
            app.UITable2.ColumnName = {'id_res'; 'name_res'; 'dim_res'; 'default_val'; 'name_dep'; 'color'; 'id_project'; 'description'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true true true true true false true];
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.AddarowButton = uibutton(app.LayersResourcesTab, 'push');
            app.AddarowButton.ButtonPushedFcn = createCallbackFcn(app, @AddarowButtonPushed, true);
            app.AddarowButton.Text = 'Add a row';
            app.DeleteSelectedButton = uibutton(app.LayersResourcesTab, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.UpdatetableButton = uibutton(app.LayersResourcesTab, 'push');
            app.UpdatetableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatetableButtonPushed, true);
            app.UpdatetableButton.Text = 'Update table';
            app.UITable6 = uitable(app.LayersResourcesTab);
            app.UITable6.ColumnName = {'id_res'; 'name_res'; 'id_project'};
            app.UITable6.RowName = {};
            app.UITable6.ColumnEditable = [false false false];
            app.resourcestobedeletedLabel = uilabel(app.LayersResourcesTab);
            app.resourcestobedeletedLabel.Text = 'resources to be deleted';
            app.ColorpickerButton_2 = uibutton(app.LayersResourcesTab, 'push');
            app.ColorpickerButton_2.ButtonPushedFcn = createCallbackFcn(app, @ColorpickerButton_2Pushed, true);
            app.ColorpickerButton_2.Text = 'Color picker';
            app.colorEditField_2Label = uilabel(app.LayersResourcesTab);
            app.colorEditField_2Label.Text = 'color';
            app.colorEditField_2 = uieditfield(app.LayersResourcesTab, 'text');
            app.colorEditField_2.ValueChangedFcn = createCallbackFcn(app, @colorEditField_2ValueChanged, true);
            app.CleanPortsButton = uibutton(app.LayersResourcesTab, 'push');
            app.CleanPortsButton.ButtonPushedFcn = createCallbackFcn(app, @CleanPortsButtonPushed, true);
            app.CleanPortsButton.Text = 'Clean Ports';
            app.UITable2_2 = uitable(app.LayersResourcesTab);
            app.UITable2_2.ColumnName = {'entity_name'; 'entity_type'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = [false false];
            app.entitieswithselectedresourceLabel = uilabel(app.LayersResourcesTab);
            app.entitieswithselectedresourceLabel.Text = 'entities with selected resource';
            app.RoutingTab = uitab(app.TabGroup);
            app.RoutingTab.Title = 'Routing';
            app.ProjectEditField_6Label = uilabel(app.RoutingTab);
            app.ProjectEditField_6Label.Text = 'Project';
            app.ProjectEditField_6 = uieditfield(app.RoutingTab, 'text');
            app.ProjectEditField_6.Editable = 'off';
            app.RoutesPanel = uipanel(app.RoutingTab);
            app.RoutesPanel.Title = 'Routes';
            app.UITable15 = uitable(app.RoutesPanel);
            app.UITable15.ColumnName = {'id_route'; 'id_routing'; 'routing_name'; 'name_res'; 'dim_res'; 'res_index'; 'out_port'};
            app.UITable15.RowName = {};
            app.UITable15.ColumnEditable = [false false false false false true true];
            app.UITable15.CellSelectionCallback = createCallbackFcn(app, @UITable15CellSelection, true);
            app.LoadRoutesButton = uibutton(app.RoutesPanel, 'push');
            app.LoadRoutesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRoutesButtonPushed, true);
            app.LoadRoutesButton.Text = 'Load Routes';
            app.AddarowButton_6 = uibutton(app.RoutesPanel, 'push');
            app.AddarowButton_6.ButtonPushedFcn = createCallbackFcn(app, @AddarowButton_6Pushed, true);
            app.AddarowButton_6.Text = 'Add a row';
            app.DeleteSelectedButton_6 = uibutton(app.RoutesPanel, 'push');
            app.DeleteSelectedButton_6.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButton_6Pushed, true);
            app.DeleteSelectedButton_6.Text = 'Delete Selected';
            app.UpdateTableButton_6 = uibutton(app.RoutesPanel, 'push');
            app.UpdateTableButton_6.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButton_6Pushed, true);
            app.UpdateTableButton_6.Text = 'Update Table';
            app.UITable16 = uitable(app.RoutesPanel);
            app.UITable16.ColumnName = {'id_route'; 'id_routing'; 'routing_name'; 'name_res'; 'res_index'; 'out_port'};
            app.UITable16.RowName = {};
            app.UITable16.ColumnEditable = [false false false];
            app.RoutestobedeletedLabel = uilabel(app.RoutesPanel);
            app.RoutestobedeletedLabel.Text = 'Routes to be deleted';
            app.RoutersPanel = uipanel(app.RoutingTab);
            app.RoutersPanel.Title = 'Routers';
            app.UITable17 = uitable(app.RoutersPanel);
            app.UITable17.ColumnName = {'id_routing'; 'routing_name'; 'name_res'; 'id_res'};
            app.UITable17.RowName = {};
            app.UITable17.ColumnEditable = [false true false false];
            app.UITable17.CellSelectionCallback = createCallbackFcn(app, @UITable17CellSelection, true);
            app.LoadRoutersButton = uibutton(app.RoutersPanel, 'push');
            app.LoadRoutersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRoutersButtonPushed, true);
            app.LoadRoutersButton.Text = 'Load Routers';
            app.AddarowButton_7 = uibutton(app.RoutersPanel, 'push');
            app.AddarowButton_7.ButtonPushedFcn = createCallbackFcn(app, @AddarowButton_7Pushed, true);
            app.AddarowButton_7.Text = 'Add a row';
            app.DeleteSelectedButton_7 = uibutton(app.RoutersPanel, 'push');
            app.DeleteSelectedButton_7.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButton_7Pushed, true);
            app.DeleteSelectedButton_7.Text = 'Delete Selected';
            app.UpdateTableButton_7 = uibutton(app.RoutersPanel, 'push');
            app.UpdateTableButton_7.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButton_7Pushed, true);
            app.UpdateTableButton_7.Text = 'Update Table';
            app.UITable18 = uitable(app.RoutersPanel);
            app.UITable18.ColumnName = {'id_routing'; 'routing_name'; 'id_res'};
            app.UITable18.RowName = {};
            app.UITable18.ColumnEditable = [false false false];
            app.RouterstobedeletedLabel = uilabel(app.RoutersPanel);
            app.RouterstobedeletedLabel.Text = 'Routers to be deleted';
            app.LinkStateTab = uitab(app.TabGroup);
            app.LinkStateTab.Title = 'Link State';
            app.UITable4 = uitable(app.LinkStateTab);
            app.UITable4.ColumnName = {'id_link'; 'link_name'; 'link_val'; 'id_project'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = true;
            app.UITable4.CellSelectionCallback = createCallbackFcn(app, @UITable4CellSelection, true);
            app.LoadLinksButton = uibutton(app.LinkStateTab, 'push');
            app.LoadLinksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLinksButtonPushed, true);
            app.LoadLinksButton.Text = 'Load Links';
            app.AddarowButton_3 = uibutton(app.LinkStateTab, 'push');
            app.AddarowButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddarowButton_3Pushed, true);
            app.AddarowButton_3.Text = 'Add a row';
            app.DeleteSelectedButton_3 = uibutton(app.LinkStateTab, 'push');
            app.DeleteSelectedButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButton_3Pushed, true);
            app.DeleteSelectedButton_3.Text = 'Delete Selected';
            app.UpdateTableButton_2 = uibutton(app.LinkStateTab, 'push');
            app.UpdateTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButton_2Pushed, true);
            app.UpdateTableButton_2.Enable = 'off';
            app.UpdateTableButton_2.Text = 'Update Table';
            app.ProjectEditField_3Label = uilabel(app.LinkStateTab);
            app.ProjectEditField_3Label.Text = 'Project';
            app.ProjectEditField_3 = uieditfield(app.LinkStateTab, 'text');
            app.ProjectEditField_3.Editable = 'off';
            app.UITable7 = uitable(app.LinkStateTab);
            app.UITable7.ColumnName = {'id_link'; 'link_name'; 'id_project'};
            app.UITable7.RowName = {};
            app.UITable7.ColumnEditable = [false false false];
            app.LinkstobedeletedLabel = uilabel(app.LinkStateTab);
            app.LinkstobedeletedLabel.Text = 'Links to be deleted';
            app.RadialDiffusionTab = uitab(app.TabGroup);
            app.RadialDiffusionTab.Title = 'Radial Diffusion';
            app.UITable11 = uitable(app.RadialDiffusionTab);
            app.UITable11.ColumnName = {'id_radial'; 'radial_name'; 'latitude'; 'longitude'; 'id_project'};
            app.UITable11.RowName = {};
            app.UITable11.ColumnEditable = [false true false false];
            app.LoadRadialButton = uibutton(app.RadialDiffusionTab, 'push');
            app.LoadRadialButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRadialButtonPushed, true);
            app.LoadRadialButton.Text = 'Load Radial';
            app.AddarowButton_4 = uibutton(app.RadialDiffusionTab, 'push');
            app.AddarowButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddarowButton_4Pushed, true);
            app.AddarowButton_4.Enable = 'off';
            app.AddarowButton_4.Text = 'Add a row';
            app.DeleteSelectedButton_4 = uibutton(app.RadialDiffusionTab, 'push');
            app.DeleteSelectedButton_4.Enable = 'off';
            app.DeleteSelectedButton_4.Text = 'Delete Selected';
            app.UpdateTableButton_3 = uibutton(app.RadialDiffusionTab, 'push');
            app.UpdateTableButton_3.Enable = 'off';
            app.UpdateTableButton_3.Text = 'Update Table';
            app.UITable12 = uitable(app.RadialDiffusionTab);
            app.UITable12.ColumnName = {'id_radial'; 'radial_name'; 'id_project'};
            app.UITable12.RowName = {};
            app.UITable12.ColumnEditable = [false false false];
            app.RadialtobedeletedLabel = uilabel(app.RadialDiffusionTab);
            app.RadialtobedeletedLabel.Text = 'Radial to be deleted';
            app.ProjectEditField_4Label = uilabel(app.RadialDiffusionTab);
            app.ProjectEditField_4Label.Text = 'Project';
            app.ProjectEditField_4 = uieditfield(app.RadialDiffusionTab, 'text');
            app.ProjectEditField_4.Editable = 'off';
            app.Save2DBTab = uitab(app.TabGroup);
            app.Save2DBTab.Title = 'Save2DB';
            app.UITable13 = uitable(app.Save2DBTab);
            app.UITable13.ColumnName = {'id_save2db'; 'name_save'; 'name_res'; 'id_res'};
            app.UITable13.RowName = {};
            app.UITable13.ColumnEditable = [false true false false];
            app.UITable13.CellEditCallback = createCallbackFcn(app, @UITable13CellEdit, true);
            app.UITable13.CellSelectionCallback = createCallbackFcn(app, @UITable13CellSelection, true);
            app.LoadSave2DBButton = uibutton(app.Save2DBTab, 'push');
            app.LoadSave2DBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSave2DBButtonPushed, true);
            app.LoadSave2DBButton.Text = 'Load Save2DB';
            app.AddarowButton_5 = uibutton(app.Save2DBTab, 'push');
            app.AddarowButton_5.ButtonPushedFcn = createCallbackFcn(app, @AddarowButton_5Pushed, true);
            app.AddarowButton_5.Text = 'Add a row';
            app.DeleteSelectedButton_5 = uibutton(app.Save2DBTab, 'push');
            app.DeleteSelectedButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButton_5Pushed, true);
            app.DeleteSelectedButton_5.Text = 'Delete Selected';
            app.UpdateTableButton_5 = uibutton(app.Save2DBTab, 'push');
            app.UpdateTableButton_5.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButton_5Pushed, true);
            app.UpdateTableButton_5.Text = 'Update Table';
            app.UITable14 = uitable(app.Save2DBTab);
            app.UITable14.ColumnName = {'id_save2db'; 'name_save'; 'name_res'; 'id_res'};
            app.UITable14.RowName = {};
            app.UITable14.ColumnEditable = [false false false false];
            app.UITable14.CellSelectionCallback = createCallbackFcn(app, @UITable14CellSelection, true);
            app.Save2DBtobedeletedLabel = uilabel(app.Save2DBTab);
            app.Save2DBtobedeletedLabel.Text = 'Save2DB to be deleted';
            app.ProjectEditField_5Label = uilabel(app.Save2DBTab);
            app.ProjectEditField_5Label.Text = 'Project';
            app.ProjectEditField_5 = uieditfield(app.Save2DBTab, 'text');
            app.ProjectEditField_5.Editable = 'off';
            app.RunManager = uitab(app.TabGroup);
            app.RunManager.Title = 'Run Manager';
            app.ModelselectionPanel = uipanel(app.RunManager);
            app.ModelselectionPanel.Title = 'Model selection';
            app.SelectmodelButton = uibutton(app.ModelselectionPanel, 'push');
            app.SelectmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmodelButtonPushed, true);
            app.SelectmodelButton.Text = 'Select model';
            app.modelnameEditFieldLabel = uilabel(app.ModelselectionPanel);
            app.modelnameEditFieldLabel.Text = 'model name';
            app.modelnameEditField = uieditfield(app.ModelselectionPanel, 'text');
            app.pathEditFieldLabel = uilabel(app.ModelselectionPanel);
            app.pathEditFieldLabel.Text = 'path';
            app.pathEditField = uieditfield(app.ModelselectionPanel, 'text');
            app.RunningpanelPanel = uipanel(app.RunManager);
            app.RunningpanelPanel.Title = 'Running panel';
            app.singlerunSwitchLabel = uilabel(app.RunningpanelPanel);
            app.singlerunSwitchLabel.Text = 'single run';
            app.singlerunSwitch = uiswitch(app.RunningpanelPanel, 'slider');
            app.singlerunSwitch.ValueChangedFcn = createCallbackFcn(app, @singlerunSwitchValueChanged, true);
            app.singlerunSwitch.Value = 'On';
            app.RunButton = uibutton(app.RunningpanelPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.runningLampLabel = uilabel(app.RunningpanelPanel);
            app.runningLampLabel.Text = 'running';
            app.runningLamp = uilamp(app.RunningpanelPanel);
            app.checkingLampLabel = uilabel(app.RunningpanelPanel);
            app.checkingLampLabel.Text = 'checking';
            app.checkingLamp = uilamp(app.RunningpanelPanel);
            app.StoprunningButton = uibutton(app.RunningpanelPanel, 'push');
            app.StoprunningButton.ButtonPushedFcn = createCallbackFcn(app, @StoprunningButtonPushed, true);
            app.StoprunningButton.Text = 'Stop running';
            app.runningtimeEditFieldLabel = uilabel(app.RunningpanelPanel);
            app.runningtimeEditFieldLabel.Text = 'running time';
            app.runningtimeEditField = uieditfield(app.RunningpanelPanel, 'numeric');
            app.EnableFastRestartSwitchLabel = uilabel(app.RunningpanelPanel);
            app.EnableFastRestartSwitchLabel.Text = 'Enable Fast Restart';
            app.EnableFastRestartSwitch = uiswitch(app.RunningpanelPanel, 'slider');
            app.EnableFastRestartSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableFastRestartSwitchValueChanged, true);
            app.modelchangingLampLabel = uilabel(app.RunningpanelPanel);
            app.modelchangingLampLabel.Text = 'model changing';
            app.modelchangingLamp = uilamp(app.RunningpanelPanel);
            app.EnableModelChangeSwitchLabel = uilabel(app.RunningpanelPanel);
            app.EnableModelChangeSwitchLabel.Text = 'Enable Model Change';
            app.EnableModelChangeSwitch = uiswitch(app.RunningpanelPanel, 'slider');
            app.MessagesEditFieldLabel = uilabel(app.RunningpanelPanel);
            app.MessagesEditFieldLabel.Text = 'Messages';
            app.MessagesEditField = uieditfield(app.RunningpanelPanel, 'text');
            app.modelresetLampLabel = uilabel(app.RunningpanelPanel);
            app.modelresetLampLabel.Text = 'model reset';
            app.modelresetLamp = uilamp(app.RunningpanelPanel);
            app.ModelpicturePanel = uipanel(app.RunManager);
            app.ModelpicturePanel.Title = 'Model picture';
            app.Image6 = uiimage(app.ModelpicturePanel);
            app.Image6.Visible = 'off';
            app.OutputRun = uitab(app.TabGroup);
            app.OutputRun.Title = 'Output Run';
            app.UITable19 = uitable(app.OutputRun);
            app.UITable19.ColumnName = {'sim_time'; 'name_entity'; 'name_var'; 'val_type'; 'val_status'};
            app.UITable19.RowName = {};
            app.LoadLastRunButton = uibutton(app.OutputRun, 'push');
            app.LoadLastRunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastRunButtonPushed, true);
            app.LoadLastRunButton.Text = 'Load Last Run';
            app.readallentitiesButton_2 = uibutton(app.OutputRun, 'push');
            app.readallentitiesButton_2.ButtonPushedFcn = createCallbackFcn(app, @readallentitiesButton_2Pushed, true);
            app.readallentitiesButton_2.Text = 'read all entities';
            app.chooseDropDown_2 = uidropdown(app.OutputRun);
            app.chooseDropDown_2.Items = {};
            app.chooseDropDown_2.ValueChangedFcn = createCallbackFcn(app, @chooseDropDown_2ValueChanged, true);
            app.chooseDropDown_2.Value = {};
            app.runDropDownLabel = uilabel(app.OutputRun);
            app.runDropDownLabel.Text = 'run';
            app.runDropDown = uidropdown(app.OutputRun);
            app.runDropDown.Items = {};
            app.runDropDown.ValueChangedFcn = createCallbackFcn(app, @runDropDownValueChanged, true);
            app.runDropDown.Value = {};
            app.TotalRecordsEditFieldLabel = uilabel(app.OutputRun);
            app.TotalRecordsEditFieldLabel.Text = 'Total Records';
            app.TotalRecordsEditField = uieditfield(app.OutputRun, 'numeric');
            app.DeleteallrunButton = uibutton(app.OutputRun, 'push');
            app.DeleteallrunButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteallrunButtonPushed, true);
            app.DeleteallrunButton.Text = 'Delete all run';
            app.SavedStateTab = uitab(app.TabGroup);
            app.SavedStateTab.Title = 'Saved State';
            app.LoadInitialStatus = uibutton(app.SavedStateTab, 'push');
            app.LoadInitialStatus.ButtonPushedFcn = createCallbackFcn(app, @LoadInitialStatusButtonPushed, true);
            app.LoadInitialStatus.Text = 'Show non zero status';
            app.UITable3_3 = uitable(app.SavedStateTab);
            app.UITable3_3.ColumnName = {'id_entity'; 'name_entity'; 'id_var'; 'name_var'; 'val_type'; 'val_status'; 'id_project'};
            app.UITable3_3.RowName = {};
            app.modelnameEditField_2Label = uilabel(app.SavedStateTab);
            app.modelnameEditField_2Label.Text = 'model name';
            app.modelnameEditField_2 = uieditfield(app.SavedStateTab, 'text');
            app.CleanStatus = uibutton(app.SavedStateTab, 'push');
            app.CleanStatus.ButtonPushedFcn = createCallbackFcn(app, @CleanStatusButtonPushed, true);
            app.CleanStatus.Text = 'Clean status';
            app.SaveStatus = uibutton(app.SavedStateTab, 'push');
            app.SaveStatus.ButtonPushedFcn = createCallbackFcn(app, @SaveStatusButtonPushed, true);
            app.SaveStatus.Text = 'Save status to model';
            app.LoadStatus = uibutton(app.SavedStateTab, 'push');
            app.LoadStatus.ButtonPushedFcn = createCallbackFcn(app, @LoadStatusButtonPushed, true);
            app.LoadStatus.Text = 'Load status from model';
            app.UITable3_4 = uitable(app.SavedStateTab);
            app.UITable3_4.ColumnName = {'id_entity'; 'id_var'; 'val_type'; 'val_status'; 'id_project'; 'name_entity'; 'name_var'; 'name_model'};
            app.UITable3_4.RowName = {};
            app.ShowModelStatus = uibutton(app.SavedStateTab, 'push');
            app.ShowModelStatus.ButtonPushedFcn = createCallbackFcn(app, @ShowModelStatusButtonPushed, true);
            app.ShowModelStatus.Text = 'Show model status';
            app.SetOL = uibutton(app.SavedStateTab, 'push');
            app.SetOL.ButtonPushedFcn = createCallbackFcn(app, @SetOLButtonPushed, true);
            app.SetOL.Text = 'Set Operative Level';
            app.Image7 = uiimage(app.SavedStateTab);
            app.Image7.ImageSource = 'left_arrow.png';
            app.Image7_2 = uiimage(app.SavedStateTab);
            app.Image7_2.ImageSource = 'right_arrow.png';
            app.Gauge = uigauge(app.SavedStateTab, 'linear');
            app.Gauge.MajorTicks = [0 50 100];
            app.Gauge.MajorTickLabels = {'0', '50%', '100%'};
            app.ReadModelInRun = uibutton(app.SavedStateTab, 'push');
            app.ReadModelInRun.Enable = 'off';
            app.ReadModelInRun.Text = 'Read model in run';
            app.ReadAllModels = uibutton(app.SavedStateTab, 'push');
            app.ReadAllModels.ButtonPushedFcn = createCallbackFcn(app, @ReadAllModelsButtonPushed, true);
            app.ReadAllModels.Text = 'Read all models';
            app.modelsDropDown = uidropdown(app.SavedStateTab);
            app.modelsDropDown.Items = {};
            app.modelsDropDown.ValueChangedFcn = createCallbackFcn(app, @modelsDropDownValueChanged, true);
            app.modelsDropDown.Value = {};
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.WriteentitiestofileExcellPanel = uipanel(app.UtilitiesTab);
            app.WriteentitiestofileExcellPanel.Title = 'Write entities to file Excell';
            app.WriteEntitiestoFIleButton = uibutton(app.UtilitiesTab, 'push');
            app.WriteEntitiestoFIleButton.ButtonPushedFcn = createCallbackFcn(app, @WriteEntitiestoFIleButtonPushed, true);
            app.WriteEntitiestoFIleButton.Text = 'Write Entities to FIle';
            app.EntityprocessedEditFieldLabel = uilabel(app.UtilitiesTab);
            app.EntityprocessedEditFieldLabel.Text = 'Entity processed';
            app.EntityprocessedEditField = uieditfield(app.UtilitiesTab, 'text');
            app.Gauge_2 = uigauge(app.UtilitiesTab, 'linear');
            app.Gauge_2.MajorTicks = [0 50 100];
            app.Gauge_2.MajorTickLabels = {'0', '50%', '100%'};
            app.RepaintblocksaccordingtoaselectedstatevariablePanel = uipanel(app.UtilitiesTab);
            app.RepaintblocksaccordingtoaselectedstatevariablePanel.Title = 'Re-paint blocks according to a selected state variable';
            app.ReadEntitiesandStateVariablesButton = uibutton(app.RepaintblocksaccordingtoaselectedstatevariablePanel, 'push');
            app.ReadEntitiesandStateVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadEntitiesandStateVariablesButtonPushed, true);
            app.ReadEntitiesandStateVariablesButton.Text = 'Read Entities and State Variables';
            app.SelectonestatevariableLabel = uilabel(app.RepaintblocksaccordingtoaselectedstatevariablePanel);
            app.SelectonestatevariableLabel.Text = 'Select one state variable:';
            app.StateVariablesDropDownLabel = uilabel(app.RepaintblocksaccordingtoaselectedstatevariablePanel);
            app.StateVariablesDropDownLabel.Text = 'State Variables';
            app.StateVariablesDropDown = uidropdown(app.RepaintblocksaccordingtoaselectedstatevariablePanel);
            app.StateVariablesDropDown.Items = {};
            app.StateVariablesDropDown.Value = {};
            app.RepaintEntitiesButton = uibutton(app.RepaintblocksaccordingtoaselectedstatevariablePanel, 'push');
            app.RepaintEntitiesButton.ButtonPushedFcn = createCallbackFcn(app, @RepaintEntitiesButtonPushed, true);
            app.RepaintEntitiesButton.Text = 'Re-paint Entities';
            app.ReverseCheckBox = uicheckbox(app.RepaintblocksaccordingtoaselectedstatevariablePanel);
            app.ReverseCheckBox.Text = 'Reverse';
            app.Panel3 = uipanel(app.UtilitiesTab);
            app.Panel3.Title = 'Panel3';
            app.SaveGraphButton = uibutton(app.Panel3, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Text = 'Save Graph';
            app.Image_2 = uiimage(app.CISIAproDesignUIFigure);
            app.Image_2.ImageSource = 'CISIApro_v4.png';
            app.Image3_3 = uiimage(app.CISIAproDesignUIFigure);
            app.Image3_3.ImageSource = 'MCIPlab.it.png';
            app.TopImage = uiimage(app.CISIAproDesignUIFigure);
            app.TopImage.ImageSource = 'icons8-aggiungi-database-64 (1).png';
            app.Label = uilabel(app.CISIAproDesignUIFigure);
            app.Label.Text = '3.7.6';
            % Show the figure after all components are created
            app.CISIAproDesignUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CISIAproDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CISIAproDesignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CISIAproDesignUIFigure)
        end
    end
end